classdef Release_2024_05 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SEAT                           matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadXMLlogMenu                 matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        QuitMenu                       matlab.ui.container.Menu
        DatabaseMenu                   matlab.ui.container.Menu
        DeleteselectedsessionsMenu     matlab.ui.container.Menu
        LoadonselectionMenu            matlab.ui.container.Menu
        ReportsMenu                    matlab.ui.container.Menu
        SessionMenu                    matlab.ui.container.Menu
        DriversPerformanceMenu         matlab.ui.container.Menu
        StintsMenu                     matlab.ui.container.Menu
        TimecardsMenu                  matlab.ui.container.Menu
        PitstopsMenu                   matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        DatabaseTab                    matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        UITableHeaders                 matlab.ui.control.Table
        ReportsTab                     matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        SummaryPanel                   matlab.ui.container.Panel
        GridLayout_4                   matlab.ui.container.GridLayout
        S3EditField                    matlab.ui.control.EditField
        Sector3EditFieldLabel          matlab.ui.control.Label
        S2EditField                    matlab.ui.control.EditField
        Sector2EditFieldLabel          matlab.ui.control.Label
        S1EditField                    matlab.ui.control.EditField
        Sector1EditFieldLabel          matlab.ui.control.Label
        FLEditField                    matlab.ui.control.EditField
        FastestlapEditFieldLabel       matlab.ui.control.Label
        TBEditField                    matlab.ui.control.EditField
        TheoreticalbestEditFieldLabel  matlab.ui.control.Label
        percintrafficEditField         matlab.ui.control.NumericEditField
        intrafficLabel                 matlab.ui.control.Label
        OvertakesEditField             matlab.ui.control.NumericEditField
        OvertakesEditFieldLabel        matlab.ui.control.Label
        SessionPanel                   matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        TimeminEditField               matlab.ui.control.NumericEditField
        TimeminEditFieldLabel          matlab.ui.control.Label
        LapsEditField                  matlab.ui.control.NumericEditField
        LapsEditFieldLabel             matlab.ui.control.Label
        LengthEditField_km             matlab.ui.control.NumericEditField
        LengthkmLabel                  matlab.ui.control.Label
        TrackEditField                 matlab.ui.control.EditField
        TrackEditFieldLabel            matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.EditField
        TimeEditFieldLabel             matlab.ui.control.Label
        DateEditField                  matlab.ui.control.EditField
        DateEditFieldLabel             matlab.ui.control.Label
        EventEditField                 matlab.ui.control.EditField
        EventEditFieldLabel            matlab.ui.control.Label
        ModEditField                   matlab.ui.control.EditField
        ModEditFieldLabel              matlab.ui.control.Label
        SettingEditField               matlab.ui.control.EditField
        SettingEditFieldLabel          matlab.ui.control.Label
        TypeEditField                  matlab.ui.control.EditField
        TypeEditFieldLabel             matlab.ui.control.Label
        TabGroupReports                matlab.ui.container.TabGroup
        SessionTab                     matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        UITableSession                 matlab.ui.control.Table
        DriversTab                     matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        UITableDriversPerformance      matlab.ui.control.Table
        StintsTab                      matlab.ui.container.Tab
        GridLayout26                   matlab.ui.container.GridLayout
        UITableStints                  matlab.ui.control.Table
        PitstopsTab                    matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        UITablePitstops                matlab.ui.control.Table
        GraphsTab                      matlab.ui.container.Tab
        GridLayout15                   matlab.ui.container.GridLayout
        OptionsPanel                   matlab.ui.container.Panel
        GridLayout29                   matlab.ui.container.GridLayout
        ReferenceCarDropDown           matlab.ui.control.DropDown
        ReferencecarDropDownLabel      matlab.ui.control.Label
        DriversCheckBox                matlab.ui.control.CheckBox
        OvertakesCheckBox              matlab.ui.control.CheckBox
        FuelAddedCheckBox              matlab.ui.control.CheckBox
        FuelRemainingCheckBox          matlab.ui.control.CheckBox
        CompoundsCheckBox              matlab.ui.control.CheckBox
        TrafficCheckBox                matlab.ui.control.CheckBox
        TabGroupGraphs                 matlab.ui.container.TabGroup
        GapsTab                        matlab.ui.container.Tab
        GridLayout25                   matlab.ui.container.GridLayout
        GapsAxes                       matlab.ui.control.UIAxes
        LapsTab                        matlab.ui.container.Tab
        GridLayout17                   matlab.ui.container.GridLayout
        LapsAxes                       matlab.ui.control.UIAxes
        SectorsTab                     matlab.ui.container.Tab
        GridLayout18                   matlab.ui.container.GridLayout
        S3Axes                         matlab.ui.control.UIAxes
        S2Axes                         matlab.ui.control.UIAxes
        S1Axes                         matlab.ui.control.UIAxes
        PositionsTab                   matlab.ui.container.Tab
        GridLayout21                   matlab.ui.container.GridLayout
        PositionAxes                   matlab.ui.control.UIAxes
        PaceTab                        matlab.ui.container.Tab
        GridLayout28                   matlab.ui.container.GridLayout
        SortedTimeAxes                 matlab.ui.control.UIAxes
        PaceAxes                       matlab.ui.control.UIAxes
        FuelTab                        matlab.ui.container.Tab
        GridLayout22                   matlab.ui.container.GridLayout
        FuelAxes                       matlab.ui.control.UIAxes
        WearTab                        matlab.ui.container.Tab
        GridLayout23                   matlab.ui.container.GridLayout
        WearRRAxes                     matlab.ui.control.UIAxes
        WearRLAxes                     matlab.ui.control.UIAxes
        WearFRAxes                     matlab.ui.control.UIAxes
        WearFLAxes                     matlab.ui.control.UIAxes
        PitsTab                        matlab.ui.container.Tab
        GridLayout27                   matlab.ui.container.GridLayout
        PitStopsAxes                   matlab.ui.control.UIAxes
        UITableDrivers                 matlab.ui.control.Table
        SelectallCheckBox              matlab.ui.control.CheckBox
        FuelPanel                      matlab.ui.container.Panel
        GridLayout_3                   matlab.ui.container.GridLayout
        minEditField                   matlab.ui.control.NumericEditField
        minEditFieldLabel              matlab.ui.control.Label
        lapsEditField                  matlab.ui.control.NumericEditField
        lapsEditFieldLabel             matlab.ui.control.Label
        literskmEditField              matlab.ui.control.NumericEditField
        literskmEditFieldLabel         matlab.ui.control.Label
        literslapEditField             matlab.ui.control.NumericEditField
        literslapEditFieldLabel        matlab.ui.control.Label
        caplitersEditField             matlab.ui.control.NumericEditField
        caplitersEditFieldLabel        matlab.ui.control.Label
    end


    properties (Access = private)
        AboutApp        % Dialog app with the info about the app
        FWLogged        % True if there is data on fuel and tyre wear in the log
        Timecards       % Timecards data table
        Pitstops        % Pit stops data table

        Log             % RF2 XML log file
        Session         % Type of session (Race, Qualifying, etc.)
        Swap            % If race has driver swaps
        SessionSummary  % Session summary report generated during XML loading process
        Time            % String - unique identifier for logical indexing of general database during XML log loading process        
        Driverref       % Driver name
        Team            % Team name
        CarClass        % Car class
        CarType         % Car type
        CarNumber       % Car number
        StartPos        % Starting position (entire grid)
        FinishPos       % Finishing position (entire grid)
        ClassStartPos   % Starting position (car class)
        ClassFinishPos  % Finishing position (car class)
        Recordedlaptime % Recorded lap time in seconds
        RecordedPos     % Recorded position (entire grid)
        Lap             % Lap number
        Elapsedtime     % Elapsed time in a session
        Sector1         % Sector 1 time in seconds
        Sector2         % Sector 2 time in seconds
        Sector3         % Sector 3 time in seconds
        Fuelperc        % Fuel remaining, as fraction of 1
        Twfl            % Tyre wear front left / tyre remaining, as fraction of 1
        Twfr            % Tyre wear front right / tyre remaining, as fraction of 1
        Twrl            % Tyre wear rear left / tyre remaining, as fraction of 1
        Twrr            % Tyre wear rear right / tyre remaining, as fraction of 1
        Twa             % Tyre wear average / tyre remaining, as fraction of 1
        Ftyre           % Front tyres compound
        Rtyre           % Rear tyres compound
        Pitattribute    % Binary indication of pit in lap
        Fuelcons        % Fuel consumption
        Carstoplot      % Array of driver names selected in the drivers table used for all graphing functions
        Plottyre        % Tyre compound name to plot
        StintLaps       % Laps in a stint
        StintId         % Stint Id
        Tyrestint       % Stint length (laps), since last tyre change
        SessionResults  % XML struct with the summary of session results
        Pre             % Size of preallocation array

        Twa_lap         % average tyre wear for every lap, as fraction of 1
        Twfl_lap        % average wear of front left tyre for every lap, as fraction of 1
        Twfr_lap        % average wear of front right tyre for every lap, as fraction of 1
        Twrl_lap        % average wear of rear left tyre for every lap, as fraction of 1
        Twrr_lap        % average wear of rear right tyre for every lap, as fraction of 1

        % Race session only parameters
        Racetime        % Accumulated race time in seconds
        Position        % Calculated position
        PositionClass   % Calculated class position
        Gaptoleader     % Gap to race leader in seconds
        GaptoClassleader% Gap to class leader in seconds
        Gapahead        % Gap to the car ahead in seconds
        Gapbehind       % Gap to the car behind in seconds
        Note            % Lap notes        
        Overtake        % Overtake flag
        Traffic         % Traffic flag
        Safetycar       % Safety car deployment flag
        Fueladded       % Fuel added during pitstop, as fraction of 1
        TyreIn          % Tyre used on the pit-in lap
        TyreOut         % Tyre used on the pit-out lap
        EstimatedStopLapTime    % Estimated pit stop time loss based on the adjusted lap times
        EstimatedStopSectorTime % Estimated pit stop time loss based on the adjusted sector times
        Refuelingattribute      % Binary indication of refueling
        Tyrechangeattribute     % Binary indication of tyre change
        Driverchangeattribute   % Binary indication of driver change
    end

    methods (Access = private)

        function unpackLog(app)

            % Run checks and preallocate arrays
            pre = 0;
            for a = 1:length(app.SessionResults)
                if isfield(app.SessionResults(a),'Lap') && isfield(app.SessionResults(a).Lap,'numAttribute') % count all lap attempts (drivers that stayed in the garage are excluded)
                    sum = length(app.SessionResults(a).Lap);
                    pre = pre + sum;
                end
            end

            driverref = strings(pre,1);
            team = strings(pre,1);
            carclass = strings(pre,1);
            cartype = strings(pre,1);
            carnumber = zeros(pre,1);
            startpos = zeros(pre,1);
            finishpos = zeros(pre,1);
            classstartpos = zeros(pre,1);
            classfinishpos = zeros(pre,1);
            recordedlaptime = zeros(pre,1);
            recordedpos = zeros(pre,1);
            lap = zeros(pre,1);
            elapsedtime = zeros(pre,1);
            sector1 = zeros(pre,1);
            sector2 = zeros(pre,1);
            sector3 = zeros(pre,1);
            fuelperc = zeros(pre,1);
            fuelcons = zeros(pre,1);
            twfl = zeros(pre,1);
            twfr = zeros(pre,1);
            twrl = zeros(pre,1);
            twrr = zeros(pre,1);
            ftyre = strings(pre,1);
            rtyre = strings(pre,1);
            pitattribute = zeros(pre,1);

            % Check and load data
            db = 1;
            lapCount = 1;
            for a = 1:length(app.SessionResults) % for all teams in a session
                if isfield(app.SessionResults(a).Lap,'numAttribute') % if driver has a name and completed at least 1 lap
                    complLaps = length(app.SessionResults(a).Lap); % completed laps for a driver 'a'

                    % Save driver-constant variables
                    % Driver names
                    if isfield(app.SessionResults(a),'Swap') % race had driver swaps
                        if length(app.SessionResults(a).Swap) > 1 % team had at least one driver swap
                            for c = 1:length(app.SessionResults(a).Swap)
                                lapsDriven = app.SessionResults(a).Swap(c).endLapAttribute - app.SessionResults(a).Swap(c).startLapAttribute;
                                driverref(lapCount:lapCount+lapsDriven) = app.SessionResults(a).Swap(c).Text;
                                lapCount = lapCount + lapsDriven + 1;
                            end
                        else % team had no driver swaps
                            lapsDriven = app.SessionResults(a).Laps;
                            driverref(lapCount:lapCount+lapsDriven-1) = app.SessionResults(a).Name;
                            lapCount = lapCount + lapsDriven;
                        end
                    elseif isfield(app.SessionResults(a),'Name') % team had no driver swaps and driver set at least 1 lap
                        driverref(db:db+complLaps-1) = app.SessionResults(a).Name;
                    else % race had no driver swaps and driver set no laps
                        driverref(db:db+complLaps-1) = NaN;
                    end

                    % Team names
                    if isfield(app.SessionResults(a),'TeamName')
                        team(db:db+complLaps-1) = app.SessionResults(a).TeamName;
                    else
                        team(db:db+complLaps-1) = NaN;
                    end

                    % Car classes
                    if isfield(app.SessionResults(a),'CarClass')
                        carclass(db:db+complLaps-1) = app.SessionResults(a).CarClass;
                    else
                        carclass(db:db+complLaps-1) = NaN;
                    end

                    % Car types
                    if isfield(app.SessionResults(a),'CarType')
                        cartype(db:db+complLaps-1) = app.SessionResults(a).CarType;
                    else
                        cartype(db:db+complLaps-1) = NaN;
                    end

                    % Car numbers
                    if isfield(app.SessionResults(a),'CarNumber')
                        carnumber(db:db+complLaps-1) = app.SessionResults(a).CarNumber;
                    else
                        carnumber(db:db+complLaps-1) = NaN;
                    end

                    % Starting positions (entire grid)
                    if isfield(app.SessionResults(a),'GridPos') && isequal(class(app.SessionResults(a).GridPos),'double')
                        startpos(db:db+complLaps-1) = double(app.SessionResults(a).GridPos);
                    else
                        startpos(db:db+complLaps-1) = NaN;
                    end

                    % Finishing positions (entire grid)
                    if isfield(app.SessionResults(a),'Position') && isequal(class(app.SessionResults(a).Position),'double')
                        finishpos(db:db+complLaps-1) = double(app.SessionResults(a).Position);
                    else
                        finishpos(db:db+complLaps-1) = NaN;
                    end

                    % Starting positions (car class)
                    if isfield(app.SessionResults(a),'ClassGridPos') && isequal(class(app.SessionResults(a).ClassGridPos),'double')
                        classstartpos(db:db+complLaps-1) = double(app.SessionResults(a).ClassGridPos);
                    else
                        classstartpos(db:db+complLaps-1) = NaN;
                    end

                    % Finishing positions (car class)
                    if isfield(app.SessionResults(a),'ClassPosition') && isequal(class(app.SessionResults(a).ClassPosition),'double')
                        classfinishpos(db:db+complLaps-1) = double(app.SessionResults(a).ClassPosition);
                    else
                        classfinishpos(db:db+complLaps-1) = NaN;
                    end

                    % Save lap-specific variables
                    for b = 1:complLaps % for every lap

                        % Lap times in seconds
                        if isfield(app.SessionResults(a).Lap(b),'Text') && isequal(class(app.SessionResults(a).Lap(b).Text),'double')
                            recordedlaptime(db) = double(app.SessionResults(a).Lap(b).Text);
                        else
                            recordedlaptime(db) = NaN;
                        end

                        % Lap numbers
                        if isfield(app.SessionResults(a).Lap(b),'numAttribute') && isequal(class(app.SessionResults(a).Lap(b).numAttribute),'double')
                            lap(db) = double(app.SessionResults(a).Lap(b).numAttribute);
                        else
                            lap(db) = NaN;
                        end

                        % Recorded position for every lap
                        if isfield(app.SessionResults(a).Lap(b),'pAttribute') && isequal(class(app.SessionResults(a).Lap(b).pAttribute),'double')
                            recordedpos(db) = double(app.SessionResults(a).Lap(b).pAttribute);
                        else
                            recordedpos(db) = NaN;
                        end

                        % Elapsed time in seconds
                        if isfield(app.SessionResults(a).Lap(b),'etAttribute') && isequal(class(app.SessionResults(a).Lap(b).etAttribute),'double')
                            elapsedtime(db) = double(app.SessionResults(a).Lap(b).etAttribute);
                        else
                            elapsedtime(db) = NaN;
                        end

                        % Sector 1 times in seconds
                        if isfield(app.SessionResults(a).Lap(b),'s1Attribute') && isequal(class(app.SessionResults(a).Lap(b).s1Attribute),'double')
                            sector1(db) = double(app.SessionResults(a).Lap(b).s1Attribute);
                        else
                            sector1(db) = NaN;
                        end

                        % Sector 2 times in seconds
                        if isfield(app.SessionResults(a).Lap(b),'s2Attribute') && isequal(class(app.SessionResults(a).Lap(b).s2Attribute),'double')
                            sector2(db) = double(app.SessionResults(a).Lap(b).s2Attribute);
                        else
                            sector2(db) = NaN;
                        end

                        % Sector 3 times in seconds
                        if isfield(app.SessionResults(a).Lap(b),'s3Attribute') && isequal(class(app.SessionResults(a).Lap(b).s3Attribute),'double')
                            sector3(db) = double(app.SessionResults(a).Lap(b).s3Attribute);
                        else
                            sector3(db) = NaN;
                        end

                        % Remaining fuel as fraction of 1
                        if isfield(app.SessionResults(a).Lap(b),'fuelAttribute') && isequal(class(app.SessionResults(a).Lap(b).fuelAttribute),'double')
                            fuelperc(db) = app.SessionResults(a).Lap(b).fuelAttribute;

                            % fuel consumption
                            if b ~= 1 && fuelperc(db-1) > fuelperc(db)
                                fuelcons(db) = fuelperc(db-1) - fuelperc(db);
                            else
                                fuelcons(db) = NaN;
                            end
                        else
                            fuelperc(db) = NaN;
                            fuelcons(db) = NaN;
                        end

                        % Remaining front left tyre life as fraction of 1
                        if isfield(app.SessionResults(a).Lap(b),'twflAttribute')
                            twfl(db) = app.SessionResults(a).Lap(b).twflAttribute;
                        else
                            twfl(db) = NaN;
                        end

                        % Remaining front right tyre life as fraction of 1
                        if isfield(app.SessionResults(a).Lap(b),'twfrAttribute')
                            twfr(db) = app.SessionResults(a).Lap(b).twfrAttribute;
                        else
                            twfr(db) = NaN;
                        end

                        % Remaining rear left tyre life as fraction of 1
                        if isfield(app.SessionResults(a).Lap(b),'twrlAttribute')
                            twrl(db) = app.SessionResults(a).Lap(b).twrlAttribute;
                        else
                            twrl(db) = NaN;
                        end

                        % Remaining rear right tyre life as fraction of 1
                        if isfield(app.SessionResults(a).Lap(b),'twrrAttribute')
                            twrr(db) = app.SessionResults(a).Lap(b).twrrAttribute;
                        else
                            twrr(db) = NaN;
                        end

                        % Front tyre compound
                        if isfield(app.SessionResults(a).Lap(b),'fcompoundAttribute')
                            ftyre(db) = app.SessionResults(a).Lap(b).fcompoundAttribute;
                        else
                            ftyre(db) = NaN;
                        end

                        % Rear tyre compound
                        if isfield(app.SessionResults(a).Lap(b),'rcompoundAttribute')
                            rtyre(db) = app.SessionResults(a).Lap(b).rcompoundAttribute;
                        else
                            rtyre(db) = NaN;
                        end

                        % Pit in lap indicator
                        if isfield(app.SessionResults(a).Lap(b),'pitAttribute') && isequal(class(app.SessionResults(a).Lap(b).pitAttribute),'double')
                            pitattribute(db) = app.SessionResults(a).Lap(b).pitAttribute;
                        end

                        db = db + 1;
                    end
                end
            end

            % Remove incorrectly calculated fuel consumption
            [~, TF] = rmoutliers(fuelcons,'mean');
            fuelcons(TF) = NaN;

            % Make the same driver names unique
            uniqueDrivers = unique(driverref(~ismissing(driverref)));
            for i = 1:length(uniqueDrivers)
                manyTeams = unique(team(driverref == uniqueDrivers(i)));
                if length(manyTeams) > 1 % if "one" driver is in several different teams
                    for j = 1:length(manyTeams)
                        oldName = driverref(team == manyTeams(j) & driverref == uniqueDrivers(i));
                        newName = oldName + ' ' + j; % add identifier to a name for every team
                        driverref(team == manyTeams(j) & driverref == uniqueDrivers(i)) = newName;
                    end
                end
            end

            % Write arrays to app memory
            app.Driverref = driverref;
            app.Team = team;
            app.CarClass = carclass;
            app.CarType = cartype;
            app.CarNumber = carnumber;
            app.StartPos = startpos;
            app.FinishPos = finishpos;
            app.ClassStartPos = classstartpos;
            app.ClassFinishPos = classfinishpos;
            app.Recordedlaptime = recordedlaptime;
            app.Lap = lap;
            app.RecordedPos = recordedpos;
            app.Elapsedtime = elapsedtime;
            app.Sector1 = sector1;
            app.Sector2 = sector2;
            app.Sector3 = sector3;
            app.Fuelperc = fuelperc;
            app.Fuelcons = fuelcons;
            app.Twfl = twfl;
            app.Twfr = twfr;
            app.Twrl = twrl;
            app.Twrr = twrr;
            app.Ftyre = ftyre;
            app.Rtyre = rtyre;
            app.Pitattribute = pitattribute;

            % Other stuff that relies on hard data

            % Calculate average tyre wear
            app.Twa = mean([app.Twfl app.Twfr app.Twrl app.Twrr],2);

            % Set refueling, tyre change attributes and stint lengths
            pre = length(app.Lap);

            % preallocation
            app.Twa_lap = zeros(pre,1);
            app.Twfl_lap = zeros(pre,1);
            app.Twfr_lap = zeros(pre,1);
            app.Twrl_lap = zeros(pre,1);
            app.Twrr_lap = zeros(pre,1);
            app.StintLaps = zeros(pre,1);
            app.StintId = zeros(pre,1);
            app.Tyrestint = zeros(pre,1);

            stintId = 0; % stint id
            stintLaps = 1; % rolling sum of current lap in a stint
            tyreStintLaps = 1; % rolling sum of current lap in a tyre stint

            % main loop
            for a = 1:(pre-1)
                if app.Lap(a) ~= 1
                    % calculate lap-by-lap tyre wear
                    if app.Twa(a) > app.Twa(a-1) % tyre change detection
                        app.Twa_lap(a) = 1 - app.Twa(a);
                        app.Twfl_lap(a) = 1 - app.Twfl(a);
                        app.Twfr_lap(a) = 1 - app.Twfr(a);
                        app.Twrl_lap(a) = 1 - app.Twrl(a);
                        app.Twrr_lap(a) = 1 - app.Twrr(a);

                        tyreStintLaps = 1;
                        stintId = stintId + 1;
                        stintLaps = 1;
                    else
                        app.Twa_lap(a) = app.Twa(a-1) - app.Twa(a);
                        app.Twfl_lap(a) = app.Twfl(a-1) - app.Twfl(a);
                        app.Twfr_lap(a) = app.Twfr(a-1) - app.Twfr(a);
                        app.Twrl_lap(a) = app.Twrl(a-1) - app.Twrl(a);
                        app.Twrr_lap(a) = app.Twrr(a-1) - app.Twrr(a);

                        tyreStintLaps = tyreStintLaps + 1;

                        if app.Fuelperc(a) > app.Fuelperc(a-1) || app.Driverref(a) ~= app.Driverref(a-1) % pitted for fuel or driver change
                            stintId = stintId + 1;
                            stintLaps = 1;
                        end
                    end
                else
                    app.Twa_lap(a) = 1 - app.Twa(a);
                    app.Twfl_lap(a) = 1 - app.Twfl(a);
                    app.Twfr_lap(a) = 1 - app.Twfr(a);
                    app.Twrl_lap(a) = 1 - app.Twrl(a);
                    app.Twrr_lap(a) = 1 - app.Twrr(a);

                    stintId = stintId + 1;
                    stintLaps = 1;
                    tyreStintLaps = 1;
                end

                % save values
                app.StintId(a) = stintId;
                app.StintLaps(a) = stintLaps;
                app.Tyrestint(a) = tyreStintLaps;
            end

            app.Twa_lap(pre) = app.Twa(pre-1) - app.Twa(pre);
            app.Twfl_lap(pre) = app.Twfl(pre-1) - app.Twfl(pre);
            app.Twfr_lap(pre) = app.Twfr(pre-1) - app.Twfr(pre);
            app.Twrl_lap(pre) = app.Twrl(pre-1) - app.Twrl(pre);
            app.Twrr_lap(pre) = app.Twrr(pre-1) - app.Twrr(pre);

            app.Tyrestint(pre) = app.Tyrestint(pre-1) + 1;
            app.StintLaps(pre) = app.StintLaps(pre-1) + 1;

            if isfield(app.Log.RaceResults,'Race')
                % Calculate race session specific parameters
                % The following code works only if the matrix 'lap - driver - laptime' is
                % sorted by driverids and then laps. This data format is provided by RF2
                % automatically and no additional manupulation with the data is necessary.

                % Preallocate arrays
                racetime = zeros(pre,1);
                laptime = zeros(pre,1);
                note = strings(pre,1);
                positionAll = zeros(pre,1);
                positionClass = zeros(pre,1);
                gaptoleader = zeros(pre,1);
                gaptoclassleader = zeros(pre,1);
                gapahead = zeros(pre,1);
                gapbehind = zeros(pre,1);
                skipovertake = zeros(pre,1);
                traffic = zeros(pre,1);
                overtake = zeros(pre,1);
                safetycar = zeros(pre,1);

                sum = 0;

                % Calculate race time and repair lap time
                for a = 1:pre

                    % Checks to fill out missing lap times
                    if ~isnan(app.Recordedlaptime(a)) || a == pre % last check to aviod bound violation
                        laptime(a) = app.Recordedlaptime(a);
                    else
                        laptime(a) = app.Elapsedtime(a+1) - app.Elapsedtime(a);
                        if laptime(a) < 0
                            laptime(a) = NaN; % last lap NaNs are unrepairable
                        end
                    end

                    % Calculate total race time
                    if app.Lap(a) == 1
                        sum = laptime(a);
                    else
                        sum = sum + laptime(a);
                    end
                    racetime(a) = sum;
                end

                % Calculate position
                for a = 1:pre
                    [~,p] = sort(racetime(app.Lap == app.Lap(a)));
                    r = (1:length(racetime(app.Lap == app.Lap(a))))';
                    r(p) = r;
                    positionAll(app.Lap == app.Lap(a)) = r;

                    idx = all([app.Lap == app.Lap(a) app.CarClass == app.CarClass(a)],2);
                    [~,p] = sort(racetime(idx));
                    r = (1:length(racetime(idx)))';
                    r(p) = r;
                    positionClass(idx) = r;
                end

                % Calculate gaps on track, fuel and set pit stop flags
                for a = 1:pre

                    % Gap to leader
                    leaderracetime = min(racetime(app.Lap == app.Lap(a)));
                    gaptoleader(a) = racetime(a) - leaderracetime;

                    % gap to class leader
                    idx = all([app.Lap == app.Lap(a) app.CarClass == app.CarClass(a)],2);
                    leaderclasstime = min(racetime(idx));
                    if isempty(leaderclasstime)
                        gaptoclassleader(a) = NaN;
                    else
                        gaptoclassleader(a) = racetime(a) - leaderclasstime;
                    end

                    % Gap to position ahead
                    if positionAll(a) == 1
                        gapahead(a) = 0;
                    else
                        gapahead(a) = racetime(app.Lap == app.Lap(a) & positionAll == positionAll(a)) - racetime(app.Lap == app.Lap(a) & positionAll == (positionAll(a)-1));
                    end

                    % Gap to position behind
                    if positionAll(a) == max(positionAll(app.Lap == app.Lap(a)))
                        gapbehind(a) = NaN;
                    else
                        gapbehind(a) = racetime(app.Lap == app.Lap(a) & positionAll == (positionAll(a)+1)) - racetime(app.Lap == app.Lap(a) & positionAll == positionAll(a));
                    end

                    % Pit stops
                    if app.Pitattribute(a) == 1
                        note(a) = 'entry';
                        note(a+1) = 'exit';
                    end
                end

                % Sanity check for last lap pits with retirements
                if length(note) > length(app.Lap)
                    note(end) = [];
                end

                % Other notes
                % Traffic, start and pit stop notes
                note(app.Lap == 1) = 'start';

                % Flags
                traffic(gapahead < 1 & gapahead ~= 0) = 1;
                skipovertake(strlength(note) ~= 0) = 1; % important to set skipovertake flag after all notes were set

                % Overtakes
                for a = 1:pre
                    if skipovertake(a) == 0 % normal racing conditions
                        % single overtakes
                        if positionAll(a) > 0
                            driverahead = app.Driverref(positionAll  == (positionAll(a-1) - 1) & app.Lap == app.Lap(a-1)); % find car number for the car ahead on the last lap
                            if positionAll(a) < positionAll(a-1) & ... % car gained a position in the last lap
                                    positionAll(app.Lap == app.Lap(a) & strcmp(app.Driverref, driverahead)) > positionAll(app.Lap == app.Lap(a) & strcmp(app.Driverref, app.Driverref(a))) & ... % car ahead is now behind
                                    skipovertake(app.Lap == app.Lap(a) & strcmp(app.Driverref, driverahead)) == 0 % car ahead didn't pit
                                overtake(a) = 1;
                            else
                                overtake(a) = 0;
                            end
                        else
                            overtake(a) = 0;
                        end
                        % double overtakes
                        if positionAll(a) > 1 % no double overtakes for positions 1 and 2
                            driverahead = app.Driverref(positionAll  == (positionAll(a-1) - 2) & app.Lap == app.Lap(a-1)); % find car number for the car ahead on the last lap
                            if positionAll(a) < positionAll(a-1) & ... % car gained a position in the last lap
                                    positionAll(app.Lap == app.Lap(a) & strcmp(app.Driverref, driverahead)) > positionAll(app.Lap == app.Lap(a) & strcmp(app.Driverref, app.Driverref(a))) & ... % car ahead is now behind
                                    skipovertake(app.Lap == app.Lap(a) & strcmp(app.Driverref, driverahead)) == 0 % car ahead didn't pit
                                overtake(a) = 1;
                            end
                        end
                    else
                        overtake(a) = 0;
                    end
                end

                % Write arrays to app memory
                app.Racetime = racetime;
                app.Recordedlaptime = laptime;
                app.Note = note;
                app.PositionClass = positionClass;
                app.Gaptoleader = gaptoleader;
                app.GaptoClassleader = gaptoclassleader;
                app.Gapahead = gapahead;
                app.Gapbehind = gapbehind;
                app.Traffic = traffic;
                app.Overtake = overtake;
                app.Safetycar = safetycar;

                % Other stuff that relies on hard data (only related to race sessions)

                % Set refueling, tyre change attributes and stint lengths
                pre = length(app.Lap);

                app.Refuelingattribute = zeros(pre,1);
                app.Tyrechangeattribute = zeros(pre,1);
                app.Driverchangeattribute = zeros(pre,1);
                app.Fueladded = zeros(pre,1);
                app.TyreIn = strings(pre,1);
                app.TyreOut = strings(pre,1);
                app.EstimatedStopLapTime = zeros(pre,1);
                app.EstimatedStopSectorTime = zeros(pre,1);

                % main loop
                for a = 1:(pre-1)
                    if app.Lap(a) ~= 1
                        % repair front and rear tyre compounds data
                        if app.Twa(a) > app.Twa(a-1) && ~isequal(app.Ftyre(a),app.Ftyre(a+1))
                            app.Ftyre(a) = app.Ftyre(a+1);
                        end

                        if app.Twa(a) > app.Twa(a-1) && ~isequal(app.Rtyre(a),app.Rtyre(a+1))
                            app.Rtyre(a) = app.Rtyre(a+1);
                        end

                        % set binary attributes for refueling and tyre change
                        if app.Fuelperc(a) > app.Fuelperc(a-1)
                            if app.Pitattribute(a) == 1
                                app.Refuelingattribute(a) = 1;
                                app.Fueladded(a) = round(app.Fuelperc(a) - app.Fuelperc(a-1) + mean(app.Fuelcons(app.Driverref == app.Driverref(a)),'omitnan'),3);
                            else
                                app.Refuelingattribute(a-1) = 1;
                                app.Fueladded(a-1) = round(app.Fuelperc(a) - app.Fuelperc(a-1) + mean(app.Fuelcons(app.Driverref == app.Driverref(a)),'omitnan'),3);
                            end
                        else
                            app.Refuelingattribute(a) = 0;
                            app.Fueladded(a) = 0;
                        end

                        if app.Twa(a) > app.Twa(a-1)
                            app.Tyrechangeattribute(a) = 1;
                        else
                            app.Tyrechangeattribute(a) = 0;
                        end

                        if app.Pitattribute(a) == 1
                            app.TyreIn(a) = app.Ftyre(a-1);
                            if app.Lap(a) < max(app.Lap(app.Driverref == app.Driverref(a)))
                                if app.Lap(a) > 2
                                    app.TyreOut(a) = app.Ftyre(a+1);
                                    app.EstimatedStopLapTime(a) = app.Recordedlaptime(a) + app.Recordedlaptime(a+1) - app.Recordedlaptime(a-1)*2; % IN + OUT laps minus approximate average pace before
                                    app.EstimatedStopSectorTime(a) = app.Sector3(a) + app.Sector1(a+1) - app.Sector3(a-1) - app.Sector1(a-1); % In + OUT sector minus approximate average pace before
                                elseif app.Lap(a) == 2
                                    app.TyreOut(a) = app.Ftyre(a+1);
                                    app.EstimatedStopLapTime(a) = NaN;
                                    app.EstimatedStopSectorTime(a) = NaN;
                                end
                            else
                                app.TyreOut(a) = NaN;
                                app.EstimatedStopLapTime(a) = NaN;
                                app.EstimatedStopSectorTime(a) = NaN;
                            end
                        end

                        % set binary attributes for driver change
                        if app.Driverref(a) ~= app.Driverref(a-1)
                            app.Driverchangeattribute(a) = 1;
                        else
                            app.Driverchangeattribute(a) = 0;
                        end

                    else
                        app.TyreIn(a) = app.Ftyre(a);
                        app.TyreOut(a) = app.Ftyre(a+1);
                        app.EstimatedStopLapTime(a) = NaN;
                        app.EstimatedStopSectorTime(a) = NaN;
                        app.Driverchangeattribute(a) = 1;
                    end
                end

                % add attributes for data points out of the loop
                app.Refuelingattribute(pre) = 0;
                app.Tyrechangeattribute(pre) = 0;
                app.Driverchangeattribute(a) = 0;

            else
                % Write arrays to app memory
                app.Racetime = NaN(pre,1);
                app.Note = strings(pre,1);
                app.PositionClass = NaN(pre,1);
                app.Gaptoleader = NaN(pre,1);
                app.GaptoClassleader = NaN(pre,1);
                app.Gapahead = NaN(pre,1);
                app.Gapbehind = NaN(pre,1);
                app.Traffic = NaN(pre,1);
                app.Overtake = NaN(pre,1);
                app.Safetycar = NaN(pre,1);

                % Other stuff that relies on hard data (only related to race sessions)
                app.Refuelingattribute = NaN(pre,1);
                app.Tyrechangeattribute = NaN(pre,1);
                app.Driverchangeattribute = NaN(pre,1);
                app.Fueladded = NaN(pre,1);
                app.TyreIn = strings(pre,1);
                app.TyreOut = strings(pre,1);
                app.EstimatedStopLapTime = NaN(pre,1);
                app.EstimatedStopSectorTime = NaN(pre,1);
            end
        end

        function loadSessionDataFromXML(app)
            
            % Load type of session
            if strcmp(app.Session,'TestDay')
                app.TypeEditField.Value = 'Test day';
                app.TypeEditField.BackgroundColor = '#62B5E5';
            elseif strcmp(app.Session,'Practice')
                app.TypeEditField.Value = 'Practice';
                app.TypeEditField.BackgroundColor = '#E3E48D';
            elseif strcmp(app.Session,'Qualifying')
                app.TypeEditField.Value = 'Qualifying';
                app.TypeEditField.BackgroundColor = '#FFCD00';
            elseif strcmp(app.Session,'Warmup')
                app.TypeEditField.Value = 'Warmup';
                app.TypeEditField.BackgroundColor = '#9DD4CF';
            elseif strcmp(app.Session,'Race')
                app.TypeEditField.Value = 'Race';
                app.TypeEditField.BackgroundColor = '#86BC25';
            end

            % Load venue
            app.SettingEditField.Value = app.Log.RaceResults.Setting;

            % Load mod
            app.ModEditField.Value = app.Log.RaceResults.Mod;

            % Load event
            app.EventEditField.Value = app.Log.RaceResults.TrackEvent;

            % Load course
            app.TrackEditField.Value = app.Log.RaceResults.TrackCourse;

            % Load date and time
            str = strsplit(app.Log.RaceResults.TimeString);
            app.DateEditField.Value = str(1);
            app.TimeEditField.Value = str(2);

            % Load circuit length
            app.LengthEditField_km.Value = app.Log.RaceResults.TrackLength / 1000;

            % Load the number of laps
            if app.Log.RaceResults.RaceLaps == 0
                app.LapsEditField.Value = inf;
            else
                app.LapsEditField.Value = app.Log.RaceResults.RaceLaps;
            end

            % Load race time
            if app.Log.RaceResults.RaceTime == 0
                app.TimeminEditField.Value = inf;
            else
                app.TimeminEditField.Value = app.Log.RaceResults.RaceTime;
            end
        end

        function loadSessionDataFromMat(app)

            % Load type of session
            if strcmp(app.Session,'TestDay')
                app.TypeEditField.Value = 'Test day';
                app.TypeEditField.BackgroundColor = '#62B5E5';
            elseif strcmp(app.Session,'Practice')
                app.TypeEditField.Value = 'Practice';
                app.TypeEditField.BackgroundColor = '#E3E48D';
            elseif strcmp(app.Session,'Qualifying')
                app.TypeEditField.Value = 'Qualifying';
                app.TypeEditField.BackgroundColor = '#FFCD00';
            elseif strcmp(app.Session,'Warmup')
                app.TypeEditField.Value = 'Warmup';
                app.TypeEditField.BackgroundColor = '#9DD4CF';
            elseif strcmp(app.Session,'Race')
                app.TypeEditField.Value = 'Race';
                app.TypeEditField.BackgroundColor = '#86BC25';
            end

            % Load venue
            app.SettingEditField.Value = app.UITableHeaders.Data.setting(app.UITableHeaders.Data.select == 1);

            % Load mod
            app.ModEditField.Value = app.UITableHeaders.Data.mod(app.UITableHeaders.Data.select == 1);

            % Load event
            app.EventEditField.Value = app.UITableHeaders.Data.event(app.UITableHeaders.Data.select == 1);

            % Load course
            app.TrackEditField.Value = app.UITableHeaders.Data.track(app.UITableHeaders.Data.select == 1);

            % Load date and time
            str = strsplit(app.UITableHeaders.Data.time(app.UITableHeaders.Data.select == 1));
            app.DateEditField.Value = str(1);
            app.TimeEditField.Value = str(2);

            % Load circuit length
            app.LengthEditField_km.Value = app.UITableHeaders.Data.trackLengthKm(app.UITableHeaders.Data.select == 1);

            % Load the number of laps
            if app.UITableHeaders.Data.raceLaps(app.UITableHeaders.Data.select == 1) == 0
                app.LapsEditField.Value = inf;
            else
                app.LapsEditField.Value = app.UITableHeaders.Data.raceLaps(app.UITableHeaders.Data.select == 1);
            end

            % Load race time
            if app.UITableHeaders.Data.raceTime(app.UITableHeaders.Data.select == 1) == 0
                app.TimeminEditField.Value = inf;
            else
                app.TimeminEditField.Value = app.UITableHeaders.Data.raceTime(app.UITableHeaders.Data.select == 1);
            end

            % Driver swaps
            if strcmp(app.UITableHeaders.Data.swaps(app.UITableHeaders.Data.select == 1),"Yes")
                app.Swap = 1;
            else
                app.Swap = 0;
            end
        end
                   
        function loadEventinfo(app)
            % Load drivers
            driverlist = unique(app.Driverref(~ismissing(app.Driverref)),'stable');
            carstoplot = false(length(driverlist),1);

            % teams and car classes
            pre = length(driverlist);

            class = strings(pre,1);
            team = strings(pre,1);
            fpos = zeros(pre,1);            

            for a = 1:pre
                class(a) = unique(app.CarClass(app.Driverref == driverlist(a)),'stable');
                team(a) = unique(app.Team(app.Driverref == driverlist(a)),'stable');
                fpos(a) = unique(app.FinishPos(app.Driverref == driverlist(a)),'stable');                
            end

            if app.Swap
                varnames = {'S','P','Team','Class'};
                t = table(carstoplot,fpos,team,class,'variableNames',varnames);
                t = unique(t,'rows');
                t = sortrows(t,2);
                app.UITableDrivers.Data = t;
                app.UITableDrivers.ColumnName = varnames;
                app.UITableDrivers.ColumnWidth = {40,40};
                app.UITableDrivers.ColumnEditable = [true false false false];
                addStyle(app.UITableDrivers,uistyle("HorizontalAlignment",'center'),'column',2);

                % reference cars in the drop-down list
                str = append("#",string(app.UITableDrivers.Data.P)," ",app.UITableDrivers.Data.Team);
                app.ReferenceCarDropDown.Items = ["Class Leader";"Race Leader";str];                
            else
                varnames = {'S','P','Name','Team','Class'};
                t = table(carstoplot,fpos,driverlist,team,class,'variableNames',varnames);
                t = sortrows(t,2);
                app.UITableDrivers.Data = t;
                app.UITableDrivers.ColumnName = varnames;
                app.UITableDrivers.ColumnWidth = {40,40};
                app.UITableDrivers.ColumnEditable = [true false false false false];
                addStyle(app.UITableDrivers,uistyle("HorizontalAlignment",'center'),'column',2);

                % reference cars in the drop-down list
                app.ReferenceCarDropDown.Items = ["Class Leader";"Race Leader";string(app.UITableDrivers.Data.Name)];
            end

            app.ReferenceCarDropDown.Value = app.ReferenceCarDropDown.Items(1);

            % Enable 'Select all' checkbox and set it to 0
            app.SelectallCheckBox.Enable = 'on';
            app.SelectallCheckBox.Value = 0;

            % Session summary fields
            if isnan(min(app.Sector1))
                app.S1EditField.Value = "N/A";
            else
                s = min(app.Sector1);
                s = seconds(s); s.Format = 'mm:ss.SSS';
                app.S1EditField.Value = string(s);
            end

            if isnan(min(app.Sector2))
                app.S1EditField.Value = "N/A";
            else
                s = min(app.Sector2);
                s = seconds(s); s.Format = 'mm:ss.SSS';
                app.S2EditField.Value = string(s);
            end

            if isnan(min(app.Sector3))
                app.S1EditField.Value = "N/A";
            else
                s = min(app.Sector3);
                s = seconds(s); s.Format = 'mm:ss.SSS';
                app.S3EditField.Value = string(s);
            end

            if isnan(min(app.Sector1)) || isnan(min(app.Sector2)) || isnan(min(app.Sector3))
                app.TBEditField.Value = "N/A";
            else
                tb = min(app.Sector1) + min(app.Sector2) + min(app.Sector3);
                tb = seconds(tb); tb.Format = 'mm:ss.SSS';
                app.TBEditField.Value = string(tb);
            end
            
            if isnan(min(app.Recordedlaptime))
                app.FLsecEditField.Value = "N/A";
            else
                fl = min(app.Recordedlaptime);
                fl = seconds(fl); fl.Format = 'mm:ss.SSS';
                app.FLEditField.Value = string(fl);
            end

            if strcmp(app.Session,'Race')
                app.OvertakesEditField.Value = sum(app.Overtake);
                app.percintrafficEditField.Value = (sum(app.Traffic) / numel(app.Traffic)) * 100;
            end

            % create tyre compound for plotting
            app.Plottyre = string(app.Ftyre);
        end

        function updateDatabase(app)            
            time = app.Log.RaceResults.TimeString;            
            % check if new entry exists in the database
            if ~isempty(app.UITableHeaders.Data) && any(all([strcmp(time,app.UITableHeaders.Data.time) strcmp(app.Session,app.UITableHeaders.Data.session)],2))
                % update selection in the headers database
                app.UITableHeaders.Data.select(:) = false;
                selectedRow = all([strcmp(time,app.UITableHeaders.Data.time) strcmp(app.Session,app.UITableHeaders.Data.session)],2);
                app.UITableHeaders.Data.select(selectedRow) = true;
                return
            else
                % create other variables
                select = logical(false);
                session = string(app.Session);
                setting = app.Log.RaceResults.Setting;
                mod = app.Log.RaceResults.Mod;
                track = app.Log.RaceResults.TrackCourse;
                event = app.Log.RaceResults.TrackEvent;
                vehicles = app.Log.RaceResults.VehiclesAllowed;
                drivers = length(unique(rmmissing(app.Driverref)));
                if app.Swap == 1
                    swaps = "Yes";
                else
                    swaps = "No";
                end
                trackLengthKm = app.Log.RaceResults.TrackLength / 1000;
                if app.Log.RaceResults.RaceLaps == 0
                    raceLaps = inf;
                else
                    raceLaps = app.Log.RaceResults.RaceLaps;
                end
                if app.Log.RaceResults.RaceTime == 0
                    raceTime = inf;
                else
                    raceTime = app.Log.RaceResults.RaceTime;
                end
                totalLaps = length(app.Lap);
                if app.Log.RaceResults.FixedSetups == 1
                    setup = "Fixed";
                else
                    setup = "Open";
                end
                fuelMult = app.Log.RaceResults.FuelMult;
                tyreMult = app.Log.RaceResults.TireMult;
                fwLogged = app.FWLogged;
                
                new = table(select,time,session,setting,mod,track,trackLengthKm,event,vehicles,raceLaps,raceTime,drivers,swaps,totalLaps,setup,fuelMult,tyreMult,fwLogged);
                
                % check if dataset is empty to set correct variable types for the table
                if isempty(app.UITableHeaders.Data)
                    app.UITableHeaders.Data = new;
                else
                    old = app.UITableHeaders.Data;
                    old.select(:,1) = false;
                    app.UITableHeaders.Data = [new; old];
                end
                
                headers = app.UITableHeaders.Data;                
                if ~isdeployed
                    save('headers.mat','headers');
                else
                    save(fullfile(ctfroot, 'SEAT', 'headers.mat'), 'headers');
                end

                app.UITableHeaders.Data.select(1) = true; % set the latest entry as selected
                
                % write data to general database
                toSave.Driverref = app.Driverref;
                toSave.Team = app.Team;
                toSave.CarClass = app.CarClass;
                toSave.CarType = app.CarType;
                toSave.CarNumber = app.CarNumber;
                toSave.StartPos = app.StartPos;
                toSave.FinishPos = app.FinishPos;
                toSave.ClassStartPos = app.ClassStartPos;
                toSave.ClassFinishPos = app.ClassFinishPos;
                toSave.Recordedlaptime = app.Recordedlaptime;
                toSave.Lap = app.Lap;
                toSave.RecordedPos = app.RecordedPos;
                toSave.Elapsedtime = app.Elapsedtime;
                toSave.Sector1 = app.Sector1;
                toSave.Sector2 = app.Sector2;
                toSave.Sector3 = app.Sector3;
                toSave.Fuelperc = app.Fuelperc;
                toSave.Twfl = app.Twfl;
                toSave.Twfr = app.Twfr;
                toSave.Twrl = app.Twrl;
                toSave.Twrr = app.Twrr;
                toSave.Ftyre = app.Ftyre;
                toSave.Rtyre = app.Rtyre;
                toSave.Pitattribute = app.Pitattribute;
                toSave.Twa = app.Twa;
                toSave.Twa_lap = app.Twa_lap;
                toSave.Twfl_lap = app.Twfl_lap;
                toSave.Twfr_lap = app.Twfr_lap;
                toSave.Twrl_lap = app.Twrl_lap;
                toSave.Twrr_lap = app.Twrr_lap;
                toSave.Tyrestint = app.Tyrestint;
                toSave.StintId = app.StintId;
                toSave.StintLaps = app.StintLaps;

                toSave.Racetime = app.Racetime;
                toSave.Note = app.Note;
                toSave.PositionClass = app.PositionClass;
                toSave.Gaptoleader = app.Gaptoleader;
                toSave.GaptoClassleader = app.GaptoClassleader;
                toSave.Gapahead = app.Gapahead;
                toSave.Gapbehind = app.Gapbehind;
                toSave.Traffic = app.Traffic;
                toSave.Fuelcons = app.Fuelcons;
                toSave.Overtake = app.Overtake;
                toSave.Safetycar = app.Safetycar;
                toSave.Refuelingattribute = app.Refuelingattribute;
                toSave.Tyrechangeattribute = app.Tyrechangeattribute;
                toSave.Driverchangeattribute = app.Driverchangeattribute;
                toSave.Fueladded = app.Fueladded;
                toSave.TyreIn = app.TyreIn;
                toSave.TyreOut = app.TyreOut;
                toSave.EstimatedStopLapTime = app.EstimatedStopLapTime;
                toSave.EstimatedStopSectorTime = app.EstimatedStopSectorTime;

                name = sprintf('%s_%s.mat',time,app.Session);
                name = strrep(name,'/','');
                name = strrep(name,':','');
                name = strrep(name,' ','_');

                if ~isdeployed
                    save(name,'-struct','toSave');
                else
                    save(fullfile(ctfroot,'SEAT',name),'-struct','toSave');
                end
            end
        end

        function loadDatabase(app)            
            % load database from .mat file
            time = app.UITableHeaders.Data.time(app.UITableHeaders.Data.select);
            sessionReference = app.UITableHeaders.Data.session(app.UITableHeaders.Data.select);

            name = sprintf('%s_%s.mat',time,sessionReference);
            name = strrep(name,'/','');
            name = strrep(name,':','');
            name = strrep(name,' ','_');

            toLoad = load(name);

            % assign values
            app.Driverref = toLoad.Driverref;
            app.Team = toLoad.Team;
            app.CarClass = toLoad.CarClass;
            app.CarType = toLoad.CarType;
            app.CarNumber = toLoad.CarNumber;
            app.StartPos = toLoad.StartPos;
            app.FinishPos = toLoad.FinishPos;
            app.ClassStartPos = toLoad.ClassStartPos;
            app.ClassFinishPos = toLoad.ClassFinishPos;
            app.Recordedlaptime = toLoad.Recordedlaptime;
            app.Lap = toLoad.Lap;
            app.RecordedPos = toLoad.RecordedPos;
            app.Elapsedtime = toLoad.Elapsedtime;
            app.Sector1 = toLoad.Sector1;
            app.Sector2 = toLoad.Sector2;
            app.Sector3 = toLoad.Sector3;
            app.Fuelperc = toLoad.Fuelperc;
            app.Twfl = toLoad.Twfl;
            app.Twfr = toLoad.Twfr;
            app.Twrl = toLoad.Twrl;
            app.Twrr = toLoad.Twrr;
            app.Ftyre = toLoad.Ftyre;
            app.Rtyre = toLoad.Rtyre;
            app.Pitattribute = toLoad.Pitattribute;
            app.Twa = toLoad.Twa;
            app.Twa_lap = toLoad.Twa_lap;
            app.Twfl_lap = toLoad.Twfl_lap;
            app.Twfr_lap = toLoad.Twfr_lap;
            app.Twrl_lap = toLoad.Twrl_lap;
            app.Twrr_lap = toLoad.Twrr_lap;
            app.Tyrestint = toLoad.Tyrestint;
            app.StintId = toLoad.StintId;
            app.StintLaps = toLoad.StintLaps;

            app.Racetime = toLoad.Racetime;
            app.Note = toLoad.Note;
            app.PositionClass = toLoad.PositionClass;
            app.Gaptoleader = toLoad.Gaptoleader;
            app.GaptoClassleader = toLoad.GaptoClassleader;
            app.Gapahead = toLoad.Gapahead;
            app.Gapbehind = toLoad.Gapbehind;
            app.Traffic = toLoad.Traffic;
            app.Fuelcons = toLoad.Fuelcons;
            app.Overtake = toLoad.Overtake;
            app.Safetycar = toLoad.Safetycar;
            app.Refuelingattribute = toLoad.Refuelingattribute;
            app.Tyrechangeattribute = toLoad.Tyrechangeattribute;
            app.Driverchangeattribute = toLoad.Driverchangeattribute;
            app.Fueladded = toLoad.Fueladded;
            app.TyreIn = toLoad.TyreIn;
            app.TyreOut = toLoad.TyreOut;
            app.EstimatedStopLapTime = toLoad.EstimatedStopLapTime;
            app.EstimatedStopSectorTime = toLoad.EstimatedStopSectorTime;
        end
       
        function generateSessionSummary(app)
            % Load session summary in the table
            laps = [app.SessionResults.Laps]';
            driver = [app.SessionResults.Name]';
            carnumber = [app.SessionResults.CarNumber]';
            class = [app.SessionResults.CarClass]';
            classpos = [app.SessionResults.ClassPosition]';
            team = [app.SessionResults.TeamName]';
            vehicle = [app.SessionResults.VehName]';
            pits = [app.SessionResults.Pitstops]';
            finishstatus = [app.SessionResults.FinishStatus]';
            fl = seconds([app.SessionResults.BestLapTime]'); fl.Format = 'mm:ss.SSS';

            pre = length(laps);
            if strcmp(app.Session,'Race')
                pos = [app.SessionResults.Position]';
                change = [app.SessionResults.GridPos]' - pos;
                if isfield(app.SessionResults,'FinishTime')
                    finishtime = seconds([app.SessionResults.FinishTime]');
                else
                    finishtime = NaN;
                end
                if isfield(app.Log.RaceResults.Race.Driver,'DNFReason')
                    dnf = [app.SessionResults.DNFReason]';
                else
                    dnf = strings(length(finishtime),1);
                end

                time = cell(pre,1);
                for a = 1:length(time)
                    if isnan(finishtime) % no cars finished the race
                        time{a} = dnf(a);
                    elseif laps(a) == max(laps) % cars finished on the lead lap
                        if finishtime(a) == min(finishtime) % race winner
                            temptime = min(finishtime);
                            temptime.Format = 'hh:mm:ss.SSS';
                            time{a} = temptime;
                        else % other cars on the lead lap
                            temptime = finishtime(a) - min(finishtime);
                            temptime.Format = 'mm:ss.SSS';
                            time{a} = sprintf('+%s',temptime);
                        end
                    elseif isequal(finishstatus(a),'Finished Normally') % other classified cars
                        lapsbehind = max(laps) - laps(a);
                        if lapsbehind == 1
                            time{a} = sprintf('+%d lap',lapsbehind);
                        else
                            time{a} = sprintf('+%d laps',lapsbehind);
                        end
                    else % non-classified cars
                        time{a} = dnf(a);
                    end
                end
            else
                pos = [app.SessionResults.LapRankIncludingDiscos]';
                change = NaN(length(pos),1);
                pits = NaN(length(pos),1);
                time = NaN(length(pos),1);
            end

            % name to save
            name = sprintf('%s_%s_Summary.mat',app.Log.RaceResults.TimeString,app.Session);
            name = strrep(name,'/','');
            name = strrep(name,':','');
            name = strrep(name,' ','_');

            varnames = {'P','Ch','CP','N','Last driver','Team','Class','Vehicle','Laps','Stops','Time','FL'};
            sessionSummary = table(pos,change,classpos,carnumber,driver,team,class,vehicle,laps,pits,time,fl,'VariableNames',varnames);
            sessionSummary = sortrows(sessionSummary,1);

            if ~isdeployed
                save(name,'sessionSummary');
            else
                save(fullfile(ctfroot,'SEAT',name),'sessionSummary');
            end

            % save to app workspace
            app.SessionSummary = sessionSummary;
        end

        function loadSessionSummary(app)
            if isempty(app.SessionSummary) % check if this call is after XML load or MAT load
                time = app.UITableHeaders.Data.time(app.UITableHeaders.Data.select);                

                name = sprintf('%s_%s_Summary.mat',time,app.Session);
                name = strrep(name,'/','');
                name = strrep(name,':','');
                name = strrep(name,' ','_');

                toLoad = load(name);
                t = toLoad.sessionSummary;
            else
                t = app.SessionSummary;
            end

            % assign values
            green = [38/255 137/255 13/255];
            red = [218/255 41/255 28/255];
            purple = [0.4940 0.1840 0.5560];

            varnames = {'P','Ch','CP','N','Last driver','Team','Class','Vehicle','Laps','Stops','Time','FL'};
            app.UITableSession.Data = t;
            app.UITableSession.ColumnName = varnames;
            app.UITableSession.ColumnWidth = {'fit','fit','fit','fit','auto','auto','auto','auto','auto','auto','auto','auto'};
            app.UITableSession.ColumnSortable = true;

            % align columns
            addStyle(app.UITableSession,uistyle("HorizontalAlignment",'right'),'column',11);
            addStyle(app.UITableSession,uistyle("HorizontalAlignment",'center'),'column',[1 2 3 4]);
            app.UITableSession.ColumnWidth = {40,40,40,40};

            % color code position changes
            if ~anynan(t.(2))
                % gains
                ind = t.(2)>0;
                [row,col] = find(ind);
                col = col*2; % second column in the UI table
                style = uistyle("FontColor",green,"FontWeight",'bold');
                addStyle(app.UITableSession,style,'cell',[row,col]);
                % losses
                ind = t.(2)<0;
                [row,col] = find(ind);
                col = col*2; % second column in the UI table
                style = uistyle("FontColor",red,"FontWeight",'bold');
                addStyle(app.UITableSession,style,'cell',[row,col]);
            end
            % color code fastest lap
            ind = (t.FL == min(t.FL));
            [row,col] = find(ind);
            col = col*12;
            style = uistyle("FontColor",purple,"FontWeight",'bold');
            addStyle(app.UITableSession,style,'cell',[row,col]);
        end

        function driverPerformance(app)
            % Load drivers performance report in the table
            pre = length(unique(app.Driverref(~ismissing(app.Driverref)),'stable'));
            pos = zeros(pre,1);
            carnumber = zeros(pre,1);
            class = strings(pre,1);
            team = strings(pre,1);
            laps = zeros(pre,1);
            time = zeros(pre,1);
            pace = zeros(pre,1);
            consistency = zeros(pre,1);
            pb = zeros(pre,1);
            tb = zeros(pre,1);
            sector1 = zeros(pre,1);
            sector2 = zeros(pre,1);
            sector3 = zeros(pre,1);
            consumption = zeros(pre,1);            

            driver = unique(app.Driverref(~ismissing(app.Driverref)),'stable');

            for a = 1:pre
                pos(a) = unique(app.FinishPos(app.Driverref == driver(a)));
                class(a) = unique(app.CarClass(app.Driverref == driver(a)));
                team(a) = unique(app.Team(app.Driverref == driver(a)));
                laps(a) = length(app.Lap(app.Driverref == driver(a)));
                consumption(a) = mean(app.Fuelcons(app.Driverref == driver(a)),"omitnan");

                temp = app.Recordedlaptime(app.Driverref == driver(a));
                time(a) = sum(temp,'omitnan');
                
                if strcmp(app.Session,'Race')
                    temp = app.Recordedlaptime(app.Driverref == driver(a) & ~app.Pitattribute);
                    pace(a) = mean(temp, "omitnan");
                    consistency(a) = std(temp,'omitnan');
                else
                    temp = app.Recordedlaptime(app.Driverref == driver(a));
                    pace(a) = mean(temp, "omitnan");
                    consistency(a) = std(temp,'omitnan');                    
                end

                pb(a) = min(app.Recordedlaptime(app.Driverref == driver(a)));
                
                if isnan(app.CarNumber(app.Driverref == driver(a)))
                    carnumber(a) = NaN;
                else
                    carnumber(a) = unique(app.CarNumber(app.Driverref == driver(a)));
                end

                tempsector = app.Sector1(app.Driverref == driver(a) & app.Sector1 > 0);                
                if isempty(tempsector)
                    sector1(a) = NaN;
                else                    
                    sector1(a) = min(tempsector);
                end

                tempsector = app.Sector2(app.Driverref == driver(a) & app.Sector2 > 0);
                if isempty(tempsector)
                    sector2(a) = NaN;
                else                    
                    sector2(a) = min(tempsector);
                end

                tempsector = app.Sector3(app.Driverref == driver(a) & app.Sector3 > 0);
                if isempty(tempsector)
                    sector3(a) = NaN;
                else                    
                    sector3(a) = min(tempsector);
                end

                if isempty(sector1) || isempty(sector2) || isempty(sector3)
                    tb(a) = NaN;
                else
                    tb(a) = sector1(a) + sector2(a) + sector3(a);
                end           
            end
            rsd = consistency ./ pace;

            pb = seconds(pb); pb.Format = 'mm:ss.SSS';
            pace = seconds(pace); pace.Format = 'mm:ss.SSS';
            tb = seconds(tb); tb.Format = 'mm:ss.SSS';
            time = seconds(time); time.Format = 'hh:mm:ss.SSS';

            varnames = {'P','N','Driver','Team','Class','Laps','Time','Pace','RSD','Fuel','FL','TB','S1','S2','S3'};
            t = table(pos,carnumber,driver,team,class,laps,time,pace,rsd,consumption,pb,tb,sector1,sector2,sector3,'variableNames',varnames);
            t = sortrows(t,[1 6]);
            app.UITableDriversPerformance.Data = t;
            app.UITableDriversPerformance.ColumnName = varnames;
            
            addStyle(app.UITableDriversPerformance,uistyle("HorizontalAlignment",'center'),'column',[1 2]);            
            app.UITableDriversPerformance.ColumnWidth = {40,40};
            
            % color code purple laps
            for a = 8:15
                ind = (t.(a) == min(t.(a)));
                [row,col] = find(ind);
                col = col*a;
                purple = [0.4940 0.1840 0.5560];
                style = uistyle("FontColor",purple,"FontWeight",'bold');
                addStyle(app.UITableDriversPerformance,style,'cell',[row,col]);
            end
        end

        function stintPerformance(app)
            % Remove previous styles
            removeStyle(app.UITableStints);

            % Create matrix for logical indexing
            idxMatrix = [app.FinishPos app.CarNumber app.Driverref app.CarClass app.Team app.StintId];
            idxMatrix(ismissing(idxMatrix(:,2)),:) = []; % remove rows with missing car numbers
            idxMatrix(ismissing(idxMatrix(:,3)),:) = []; % remove rows with missing driver names
            idxMatrix = unique(idxMatrix,'rows');

            position = str2double(idxMatrix(:,1));
            carnumber = str2double(idxMatrix(:,2));
            driver = idxMatrix(:,3);
            class = idxMatrix(:,4);
            team = idxMatrix(:,5);            
            stintId = str2double(idxMatrix(:,6));

            % Preallocation
            pre = length(stintId);
            
            laps = zeros(pre,1);
            lapstart = zeros(pre,1);
            lapend = zeros(pre,1);
            time = zeros(pre,1);            
            pace = zeros(pre,1);            
            consistency = zeros(pre,1);
            consumption = zeros(pre,1);
            fuelload = zeros(pre,1);
            FL = zeros(pre,1);
            S1 = zeros(pre,1); S2 = zeros(pre,1); S3 = zeros(pre,1);

            compound = strings(pre,1);
            twa = zeros(pre,1);
            twfl = zeros(pre,1);
            twfr = zeros(pre,1);
            twrl = zeros(pre,1);
            twrr = zeros(pre,1);

            % Fill data
            for a = 1:pre
                idx = app.StintId == stintId(a) & ~isnan(app.Recordedlaptime);
                if sum(idx) == 0
                    continue
                end                

                tyresUsed = app.Ftyre(idx);
                compound(a) = tyresUsed(1);

                laps(a) = length(app.Lap(idx));
                lapstart(a) = min(app.Lap(idx));
                lapend(a) = max(app.Lap(idx));
                time(a) = sum(app.Recordedlaptime(idx));
                consumption(a) = mean(app.Fuelcons(idx),'omitnan');
                fuelload(a) = mean(app.Fuelperc(idx),'omitnan');

                twa(a) = mean(app.Twa_lap(idx));
                twfl(a) = mean(app.Twfl_lap(idx));
                twfr(a) = mean(app.Twfr_lap(idx));
                twrl(a) = mean(app.Twrl_lap(idx));
                twrr(a) = mean(app.Twrr_lap(idx));
                
                cleanlaps = app.Recordedlaptime(idx & ~app.Pitattribute);
                pace(a) = mean(rmoutliers(cleanlaps));                
                consistency(a) = std(rmoutliers(cleanlaps));
                
                if sum(idx & app.Lap ~= 1) ~= 0
                    FL(a) = min(app.Recordedlaptime(idx & app.Lap ~= 1));
                    S1(a) = min(app.Sector1(idx & app.Lap ~= 1));
                    S2(a) = min(app.Sector2(idx & app.Lap ~= 1));
                    S3(a) = min(app.Sector3(idx & app.Lap ~= 1));
                else
                    FL(a) = NaN;
                    S1(a) = NaN;
                    S2(a) = NaN;
                    S3(a) = NaN;
                end
            end
            TB = S1 + S2 + S3;
            rsd = consistency ./ pace;

            % Format data
            time = seconds(time); time.Format = 'hh:mm:ss.SSS';            
            pace = seconds(pace); pace.Format = 'mm:ss.SSS';            
            FL = seconds(FL); FL.Format = 'mm:ss.SSS';
            S1 = seconds(S1); S1.Format = 'mm:ss.SSS';
            S2 = seconds(S2); S2.Format = 'mm:ss.SSS';
            S3 = seconds(S3); S3.Format = 'mm:ss.SSS';
            TB = seconds(TB); TB.Format = 'mm:ss.SSS';

            % Create table
            varnames = {'P','N','Driver','Team','Class','Compound','LS','LE','L','Time','Pace','RSD','Cons','Load','FL','TB','S1','S2','S3','TWA','TWFL','TWFR','TWRL','TWRR'};
            t = table(position,carnumber,driver,team,class,compound,lapstart,lapend,laps,time,pace,rsd,consumption,fuelload,FL,TB,S1,S2,S3,twa,twfl,twfr,twrl,twrr,'variableNames',varnames);
            t(t.L == 0,:) = [];
            t = sortrows(t,[1 7]);

            % Load table
            app.UITableStints.Data = t;
            app.UITableStints.ColumnName = varnames;

            % Formatting
            app.UITableStints.ColumnWidth = {40,40,'auto','auto','auto','auto',40,40,40};
            addStyle(app.UITableStints,uistyle("HorizontalAlignment",'center'),'column',[1 2]); 
        end

        function timecards(app)
            % Save timecards
            if strcmp(app.Session,'Race')
                varnames = {'FinishPos','Driver','Team','Class','Lap','Pos','ClassPos','RecordedLapTime','Note','S1','S2','S3','RaceTime','ToLeader',...
                    'ToClassLeader','GapAhead','GapBehind','FTyre','RTyre','TyreStint','Twa','Twfl','Twfr','Twrl','Twrr',...
                    'Twa_lap','Twfl_lap','Twfr_lap','Twrl_lap','Twrr_lap','FuelPerc','FuelCons'};
                t = table(app.FinishPos,app.Driverref,app.Team,app.CarClass,app.Lap,app.RecordedPos,app.PositionClass,app.Recordedlaptime,app.Note == '',app.Sector1,app.Sector2,app.Sector3,app.Racetime,app.Gaptoleader,...
                    app.GaptoClassleader,app.Gapahead,app.Gapbehind,app.Ftyre,app.Rtyre,app.Tyrestint,app.Twa,app.Twfl,app.Twfr,app.Twrl,app.Twrr,...
                    app.Twa_lap,app.Twfl_lap,app.Twfr_lap,app.Twrl_lap,app.Twrr_lap,app.Fuelperc,app.Fuelcons,'variableNames',varnames);
                t = sortrows(t,[1 5]);
                app.Timecards = t;
            else
                varnames = {'FinishPos','Driver','Team','Class','Lap','Time','S1','S2','S3',...
                    'FTyre','RTyre','TyreStint','Twa','Twfl','Twfr','Twrl','Twrr',...
                    'Twa_lap','Twfl_lap','Twfr_lap','Twrl_lap','Twrr_lap','FuelPerc','FuelCons'};
                t = table(app.FinishPos,app.Driverref,app.Team,app.CarClass,app.Lap,app.Recordedlaptime,app.Sector1,app.Sector2,app.Sector3,...
                    app.Ftyre,app.Rtyre,app.Tyrestint,app.Twa,app.Twfl,app.Twfr,app.Twrl,app.Twrr,...
                    app.Twa_lap,app.Twfl_lap,app.Twfr_lap,app.Twrl_lap,app.Twrr_lap,app.Fuelperc,app.Fuelcons,'variableNames',varnames);
                t(isnan(t.Time),:) = []; % exclude all NaN laps
                t = sortrows(t,[1 5]);
                app.Timecards = t;
            end
        end

        function pitStops(app)

            % Load pit stops in the table
            if strcmp(app.Session,'Race')
                pit_lap = app.Lap(app.Pitattribute == 1);
                driver = app.Driverref(app.Pitattribute == 1);
                class = app.CarClass(app.Pitattribute == 1);
                team = app.Team(app.Pitattribute == 1);
                est_lt = app.EstimatedStopLapTime(app.Pitattribute == 1);
                est_st = app.EstimatedStopSectorTime(app.Pitattribute == 1);
                tyre_in = app.TyreIn(app.Pitattribute == 1);
                tyre_out = app.TyreOut(app.Pitattribute == 1);
                fuel_added = app.Fueladded(app.Pitattribute == 1);

                varnames = {'L','Driver','Team','Class','Time_LT','Time_ST','In','Out','Fuel'};
                % LT - Estimated by Lap Times
                % ST - Estimated by Sector Times
                t = table(pit_lap,driver,team,class,est_lt,est_st,tyre_in,tyre_out,fuel_added,'variableNames',varnames);
                t = sortrows(t,[4 1]);
                t(isnan(t.(5)) & isnan(t.(6)),:) = [];
                t(t.(5)<0 | t.(6)<0,:) = [];
                app.UITablePitstops.Data = t;
                app.UITablePitstops.ColumnName = varnames;

                app.UITablePitstops.ColumnWidth = {40,'auto','auto','auto','auto','auto','auto','auto','auto'};
                addStyle(app.UITablePitstops,uistyle("HorizontalAlignment",'center'),'column',1);

                app.Pitstops = t;
            end
        end

        function setDefaults(app)
            % Clear & set default formatting for all tables
            app.UITableDrivers.Data = {};
            app.UITableDriversPerformance.Data = {};
            app.UITableStints.Data = {};
            app.UITableSession.Data = {};
            app.UITablePitstops.Data = {};

            removeStyle(app.UITableDrivers);
            removeStyle(app.UITableDriversPerformance);
            removeStyle(app.UITableStints);
            removeStyle(app.UITableSession);
            removeStyle(app.UITablePitstops);

            % Enable menu & field elements
            app.SessionMenu.Enable = 'on';
            app.DriversPerformanceMenu.Enable = 'on';
            app.StintsMenu.Enable = 'on';
            app.TimecardsMenu.Enable = 'on';

            app.caplitersEditField.Editable = 'on';

            if strcmp(app.Session,'Race')
                app.TrafficCheckBox.Enable = 'on';
                app.OvertakesCheckBox.Enable = 'on';
                app.DriversCheckBox.Enable = 'on';
                app.FuelAddedCheckBox.Enable = 'on';
                app.CompoundsCheckBox.Enable = 'on';
                app.FuelRemainingCheckBox.Enable = 'on';
                app.PitstopsMenu.Enable = 'on';
            else
                app.TrafficCheckBox.Enable = 'off';
                app.OvertakesCheckBox.Enable = 'off';
                app.DriversCheckBox.Enable = 'off';
                app.FuelAddedCheckBox.Enable = 'off';
                app.CompoundsCheckBox.Enable = 'off';
                app.FuelRemainingCheckBox.Enable = 'off';
                app.PitstopsMenu.Enable = 'off';
            end

            % Set default checkboxes
            app.LoadonselectionMenu.Checked = 1;

            app.TrafficCheckBox.Value = 0;
            app.CompoundsCheckBox.Value = 0;
            app.FuelRemainingCheckBox.Value = 0;
            app.FuelAddedCheckBox.Value = 0;
            app.OvertakesCheckBox.Value = 0;
            app.DriversCheckBox.Value = 0;

            % Set default fuel tank capacity
            app.caplitersEditField.Value = 1;
        end

        function updateFields(app)
            % Update consumption & distance values
            if ~isnan(mean(app.Fuelcons,'omitnan') * app.caplitersEditField.Value)
                app.literslapEditField.Value = mean(app.Fuelcons,'omitnan') * app.caplitersEditField.Value; % liters per lap                
                app.literskmEditField.Value = app.literslapEditField.Value / app.LengthEditField_km.Value; % liters per km                

                app.lapsEditField.Value = app.caplitersEditField.Value / app.literslapEditField.Value; % laps per full tank
                app.minEditField.Value = app.lapsEditField.Value * min(app.Recordedlaptime) / 60; % race minutes per full tank
            end
        end

        function plotGaps(app)
            % Clear axis
            cla(app.GapsAxes);
                        
            % Plot gaps
            PlotGaps = zeros(1,length(app.Carstoplot)); % Preallocation
            hold(app.GapsAxes,'on');

            for a = 1:length(app.Carstoplot) % Plotting loop
                if app.Swap
                    toCompare = append("#",string(app.FinishPos)," ",app.Team); % unique Id to compare
                    plot_index = toCompare == app.Carstoplot(a); % plot teams
                else
                    plot_index = app.Driverref == app.Carstoplot(a); % plot cars
                end

                % Set axes
                lapstoplot = app.Lap(plot_index); % X-axis
                if app.ReferenceCarDropDown.Value == "Class Leader"
                    gapstoplot = app.GaptoClassleader(plot_index); % Y-axis
                elseif app.ReferenceCarDropDown.Value == "Race Leader"
                    gapstoplot = app.Gaptoleader(plot_index); % Y-axis
                else
                    % get race time data
                    if app.Swap
                        toCompare = append("#",string(app.FinishPos)," ",app.Team); % unique Id to compare                        
                        refCar = app.Racetime(toCompare == app.ReferenceCarDropDown.Value); % reference car
                    else
                        refCar = app.Racetime(app.Driverref == app.ReferenceCarDropDown.Value); % reference car                        
                    end

                    selCar = app.Racetime(plot_index); % selected (plotted) car

                    % align array sizes
                    refCar(length(selCar)+1:end) = [];
                    selCar(length(refCar)+1:end) = [];

                    % calculate delta
                    gapstoplot = selCar - refCar;
                    lapstoplot(length(gapstoplot)+1:end) = [];
                end

                % Plot lines
                PlotGaps(a) = plot(app.GapsAxes, lapstoplot, gapstoplot, 'DisplayName', sprintf(app.Carstoplot(a)));

                % Plot traffic indicators
                if app.TrafficCheckBox.Value
                    traffic_index = logical(app.Traffic(plot_index));
                    scatter(app.GapsAxes, lapstoplot(traffic_index), gapstoplot(traffic_index), 18, 'black', 'filled');
                end

                % Plot overtake indicators
                if app.OvertakesCheckBox.Value
                    overtake_index = logical(app.Overtake(plot_index));
                    scatter(app.GapsAxes, lapstoplot(overtake_index), gapstoplot(overtake_index), 36, 'black', 'filled', '^');
                end

                % Plot tyre markers (for pit stops, first lap and final lap)
                if app.CompoundsCheckBox.Value
                    plottyre = app.Plottyre(plot_index); % tyre compound
                    tyre_index = logical(app.Tyrechangeattribute(plot_index) | app.Lap(plot_index) == 1);
                    text(app.GapsAxes, lapstoplot(tyre_index), gapstoplot(tyre_index), plottyre(tyre_index));
                end

                % Plot fuel remaining (for pit stops, first lap and final lap)
                if app.FuelRemainingCheckBox.Value
                    fuelremaining = string(app.Fuelperc(plot_index) * app.caplitersEditField.Value); % fuel remaining
                    fuel_index = logical(app.Refuelingattribute(plot_index) | app.Lap(plot_index) == 1 | app.Lap(plot_index) == max(app.Lap(plot_index)));
                    text(app.GapsAxes, lapstoplot(fuel_index), gapstoplot(fuel_index), fuelremaining(fuel_index));
                end

                % Plot refueling
                if app.FuelAddedCheckBox.Value
                    refueling = string(app.Fueladded(plot_index) * app.caplitersEditField.Value); % refueling amount
                    refueling_index = logical(app.Refuelingattribute(plot_index));
                    text(app.GapsAxes, lapstoplot(refueling_index), gapstoplot(refueling_index), refueling(refueling_index), 'HorizontalAlignment', 'right');
                end       

                % Plot drivers (for pit stops and first lap)
                if app.DriversCheckBox.Value
                    driver = app.Driverref(plot_index); % driver name
                    driver_index = logical(app.Driverchangeattribute(plot_index) | app.Lap(plot_index) == 1);
                    text(app.GapsAxes, lapstoplot(driver_index), gapstoplot(driver_index), driver(driver_index));
                end
            end

            % Change X-axis limit for visibility
            app.GapsAxes.XLim = [0 max(app.Lap)];

            % Set legend and turn off hold
            legend(app.GapsAxes, PlotGaps(1:length(app.Carstoplot)),'Location','northeast');
            hold(app.GapsAxes,'off');
        end

        function plotLaps(app)
            % Clear axis
            cla(app.LapsAxes);

            % Plot laps
            PlotLaps = zeros(1,length(app.Carstoplot));
            hold(app.LapsAxes,'on');

            for a = 1:length(app.Carstoplot) % Plotting loop
                if app.Swap
                    toCompare = append("#",string(app.FinishPos)," ",app.Team); % unique Id to compare
                    plot_index = toCompare == app.Carstoplot(a); % plot teams
                else
                    plot_index = app.Driverref == app.Carstoplot(a); % plot cars
                end

                % Set axes
                lapstoplot = app.Lap(plot_index); % X-axis
                laptimestoplot = app.Recordedlaptime(plot_index); % default Y-axis for lap times graph in seconds

                % Plot lines
                PlotLaps(a) = plot(app.LapsAxes, lapstoplot, laptimestoplot, 'DisplayName', sprintf(app.Carstoplot(a)));

                % Plot global display notes - race only
                if strcmp(app.Session,'Race')                    
                    % Plot traffic indicators
                    if app.TrafficCheckBox.Value
                        traffic_index = logical(app.Traffic(plot_index));
                        scatter(app.LapsAxes, lapstoplot(traffic_index), laptimestoplot(traffic_index), 18, 'black', 'filled');
                    end

                    % Plot overtake indicators
                    if app.OvertakesCheckBox.Value
                        overtake_index = logical(app.Overtake(plot_index));
                        scatter(app.LapsAxes, lapstoplot(overtake_index), laptimestoplot(overtake_index), 36, 'black', 'filled', '^');
                    end

                    % Plot tyre markers (for pit stops, first lap and final lap)
                    if app.CompoundsCheckBox.Value
                        plottyre = app.Plottyre(plot_index); % tyre compound
                        tyre_index = logical(app.Tyrechangeattribute(plot_index) | app.Lap(plot_index) == 1);
                        text(app.LapsAxes, lapstoplot(tyre_index), laptimestoplot(tyre_index), plottyre(tyre_index));
                    end

                    % Plot fuel remaining (for pit stops, first lap and final lap)
                    if app.FuelRemainingCheckBox.Value
                        fuelremaining = string(app.Fuelperc(plot_index) * app.caplitersEditField.Value); % fuel remaning
                        fuel_index = logical(app.Refuelingattribute(plot_index) | app.Lap(plot_index) == 1 | app.Lap(plot_index) == max(app.Lap(plot_index)));
                        text(app.LapsAxes, lapstoplot(fuel_index), laptimestoplot(fuel_index), fuelremaining(fuel_index));
                    end

                    % Plot refueling
                    if app.FuelAddedCheckBox.Value
                        refueling = string(app.Fueladded(plot_index) * app.caplitersEditField.Value); % refueling amount
                        refueling_index = logical(app.Refuelingattribute(plot_index));
                        text(app.LapsAxes, lapstoplot(refueling_index), laptimestoplot(refueling_index), refueling(refueling_index), 'HorizontalAlignment', 'right');
                    end

                    % Plot drivers (for pit stops and first lap)
                    if app.DriversCheckBox.Value
                        driver = app.Driverref(plot_index); % driver name
                        driver_index = logical(app.Driverchangeattribute(plot_index) | app.Lap(plot_index) == 1);
                        text(app.GapsAxes, lapstoplot(driver_index), gapstoplot(driver_index), driver(driver_index));
                    end
                end
            end

            % Change X-axis limit for visibility
            app.LapsAxes.XLim = [0 max(app.Lap)];

            % Set legend and turn off hold
            legend(app.LapsAxes, PlotLaps(1:length(app.Carstoplot)),'Location','northeast');
            hold(app.LapsAxes,'off');
        end

        function plotSectors(app)
            % Clear axis
            cla(app.S1Axes); cla(app.S2Axes); cla(app.S3Axes);
            
            % Preallocation
            PlotS1 = zeros(1,length(app.Carstoplot));
            PlotS2 = zeros(1,length(app.Carstoplot));
            PlotS3 = zeros(1,length(app.Carstoplot));

            hold(app.S1Axes,'on'); hold(app.S2Axes,'on'); hold(app.S3Axes,'on');

            for a = 1:length(app.Carstoplot)
                % Set cars to plot
                if app.Swap
                    toCompare = append("#",string(app.FinishPos)," ",app.Team); % unique Id to compare
                    plot_index = toCompare == app.Carstoplot(a); % plot teams
                else
                    plot_index = app.Driverref == app.Carstoplot(a); % plot cars
                end

                % Set axes
                lapstoplot = app.Lap(plot_index); % X-axis
                s1toplot = app.Sector1(plot_index); s2toplot = app.Sector2(plot_index); s3toplot = app.Sector3(plot_index); % default Y-axis

                % Plot lines
                PlotS1(a) = plot(app.S1Axes, lapstoplot, s1toplot, 'DisplayName', sprintf(app.Carstoplot(a)));
                PlotS2(a) = plot(app.S2Axes, lapstoplot, s2toplot, 'DisplayName', sprintf(app.Carstoplot(a)));
                PlotS3(a) = plot(app.S3Axes, lapstoplot, s3toplot, 'DisplayName', sprintf(app.Carstoplot(a)));
            end

            % Change X-axis limit for visibility
            app.S1Axes.XLim = [0 max(app.Lap)]; app.S2Axes.XLim = [0 max(app.Lap)]; app.S3Axes.XLim = [0 max(app.Lap)];

            % Legend
            legend(app.S1Axes, PlotS1(1:length(app.Carstoplot)),'Location','northeast');
            hold(app.S1Axes,'off'); hold(app.S2Axes,'off'); hold(app.S3Axes,'off');
        end

        function plotPosition(app)
            % Clear axis
            cla(app.PositionAxes);
            
            % Preallocation
            PlotPosition = zeros(1,length(app.Carstoplot));
            hold(app.PositionAxes,'on');

            for a = 1:length(app.Carstoplot)
                % Set cars to plot
                if app.Swap
                    toCompare = append("#",string(app.FinishPos)," ",app.Team); % unique Id to compare
                    plot_index = toCompare == app.Carstoplot(a); % plot teams
                else
                    plot_index = app.Driverref == app.Carstoplot(a); % plot cars
                end

                % Set axes
                lapstoplot = app.Lap(plot_index); % X-axis
                % Y-axis
                if strcmp(app.Session,'Race')
                    positiontoplot = app.PositionClass(plot_index);
                else
                    positiontoplot = app.RecordedPos(plot_index);
                end

                % Plot lines
                PlotPosition(a) = plot(app.PositionAxes, lapstoplot, positiontoplot, 'DisplayName', sprintf(app.Carstoplot(a)));
            end

            % Change X-axis limit for visibility
            app.PositionAxes.XLim = [0 max(app.Lap)];

            % Legend
            legend(app.PositionAxes, PlotPosition(1:length(app.Carstoplot)),'Location','northeast');
            hold(app.PositionAxes,'off');
        end

        function plotPace(app)
            % Clear axis
            cla(app.PaceAxes);
            cla(app.SortedTimeAxes);

            % Set cars to plot
            if app.Swap
                toCompare = append("#",string(app.FinishPos)," ",app.Team); % unique Id to compare
                plot_index = ismember(toCompare,app.Carstoplot); % plot teams
            else
                plot_index = ismember(app.Driverref,app.Carstoplot); % plot cars
            end

            % Set axes
            XAxisData = append('#',string(app.FinishPos(plot_index)),' ',app.Driverref(plot_index)); % X-axis
            YAxisData = app.Recordedlaptime(plot_index); % Y-axis

            % Remove outliers
            [YAxisData, TF] = rmoutliers(YAxisData);
            XAxisData = XAxisData(~TF);

            % Create array with median pace
            driverNames = app.Driverref(plot_index); driverNames = driverNames(~TF);
            uniqueDrivers = unique(driverNames);
            medianArray = zeros(length(YAxisData),1);
            for a = 1:length(uniqueDrivers)
                medianArray(driverNames == uniqueDrivers(a)) = median(YAxisData(driverNames == uniqueDrivers(a)),'omitnan');
            end
            [~,sortIdx] = sort(medianArray);

            % Sort by median pace
            XAxisData = XAxisData(sortIdx);
            YAxisData = YAxisData(sortIdx);

            % Boxplot
            boxplot(app.PaceAxes, YAxisData, XAxisData);

            % Plot sorted lap times                        
            PlotSortedTimes = zeros(1,length(app.Carstoplot));
            hold(app.SortedTimeAxes,'on');

            for a = 1:length(app.Carstoplot) % Plotting loop
                if app.Swap
                    toCompare = append("#",string(app.FinishPos)," ",app.Team); % unique Id to compare
                    plot_index = toCompare == app.Carstoplot(a); % plot teams
                else
                    plot_index = app.Driverref == app.Carstoplot(a); % plot cars
                end

                % Set axes                
                laptimestoplot = sort(rmoutliers(app.Recordedlaptime(plot_index))); % default Y-axis for lap times graph in seconds                
                lapstoplot = (1:length(laptimestoplot))'; % X-axis

                % Plot lines
                PlotSortedTimes(a) = plot(app.SortedTimeAxes, lapstoplot, laptimestoplot, 'DisplayName', sprintf(app.Carstoplot(a)));
            end

            % Change X-axis limit for visibility
            app.SortedTimeAxes.XLim = [0 max(app.Lap)];

            % Set legend and turn off hold
            legend(app.SortedTimeAxes, PlotSortedTimes(1:length(app.Carstoplot)),'Location','northeast');
            hold(app.SortedTimeAxes,'off');
        end

        function plotFuel(app)
            % Clear axis
            cla(app.FuelAxes);
            
            % Preallocation            
            PlotFuel = zeros(1,length(app.Carstoplot));
            hold(app.FuelAxes,'on');

            % Main plotting loop
            for a = 1:length(app.Carstoplot)
                % Set cars to plot
                if app.Swap
                    toCompare = append("#",string(app.FinishPos)," ",app.Team); % unique Id to compare
                    plot_index = toCompare == app.Carstoplot(a); % plot teams
                else
                    plot_index = app.Driverref == app.Carstoplot(a); % plot cars
                end

                % Set axes                
                % X-axis
                lapstoplot = app.Lap(plot_index);
                % Y-axis
                fueltoplot = app.Fuelperc(plot_index)*app.caplitersEditField.Value;

                % Plot lines
                PlotFuel(a) = plot(app.FuelAxes, lapstoplot, fueltoplot, 'DisplayName', sprintf(app.Carstoplot(a)));
            end

            % Change X-axis limit for visibility
            app.FuelAxes.XLim = [0 max(app.Lap)];

            % Legend
            legend(app.FuelAxes, PlotFuel(1:length(app.Carstoplot)),'Location','northeast');
            hold(app.FuelAxes,'off');
        end

        function plotTyreWear(app)
            % Clear axis
            cla(app.WearFLAxes); cla(app.WearFRAxes); cla(app.WearRLAxes); cla(app.WearRRAxes);
            
            % Preallocation            
            FLwear = zeros(1,length(app.Carstoplot));
            FRwear = zeros(1,length(app.Carstoplot));
            RLwear = zeros(1,length(app.Carstoplot));
            RRwear = zeros(1,length(app.Carstoplot));

            hold(app.WearFLAxes,'on'); hold(app.WearFRAxes,'on'); hold(app.WearRLAxes,'on'); hold(app.WearRRAxes,'on');

            % Main plotting loop
            for a = 1:length(app.Carstoplot)
                % Set cars to plot
                if app.Swap
                    toCompare = append("#",string(app.FinishPos)," ",app.Team); % unique Id to compare
                    plot_index = toCompare == app.Carstoplot(a); % plot teams
                else
                    plot_index = app.Driverref == app.Carstoplot(a); % plot cars
                end

                % Set axes
                % X-axis
                lapstoplot = app.Lap(plot_index);

                % Y-axis
                FLtoplot = app.Twfl(plot_index)*100;
                FRtoplot = app.Twfr(plot_index)*100;
                RLtoplot = app.Twrl(plot_index)*100;
                RRtoplot = app.Twrr(plot_index)*100;

                % Plot lines
                FLwear(a) = plot(app.WearFLAxes, lapstoplot, FLtoplot, 'DisplayName', sprintf(app.Carstoplot(a)));
                FRwear(a) = plot(app.WearFRAxes, lapstoplot, FRtoplot, 'DisplayName', sprintf(app.Carstoplot(a)));
                RLwear(a) = plot(app.WearRLAxes, lapstoplot, RLtoplot, 'DisplayName', sprintf(app.Carstoplot(a)));
                RRwear(a) = plot(app.WearRRAxes, lapstoplot, RRtoplot, 'DisplayName', sprintf(app.Carstoplot(a)));

                % Plot global display notes - race only
                if strcmp(app.Session,'Race')
                    % Plot tyre markers (for pit stops, first lap)                    
                    if app.CompoundsCheckBox.Value
                        plottyre = app.Plottyre(plot_index); % tyre compound
                        tyre_index = logical(app.Tyrechangeattribute(plot_index) | app.Lap(plot_index) == 1);
                        text(app.WearFLAxes, lapstoplot(tyre_index), FLtoplot(tyre_index), plottyre(tyre_index));
                        text(app.WearFRAxes, lapstoplot(tyre_index), FRtoplot(tyre_index), plottyre(tyre_index));
                        text(app.WearRLAxes, lapstoplot(tyre_index), RLtoplot(tyre_index), plottyre(tyre_index));
                        text(app.WearRRAxes, lapstoplot(tyre_index), RRtoplot(tyre_index), plottyre(tyre_index));
                    end
                end
            end

            % Change X-axis limit for visibility
            app.WearFLAxes.XLim = [0 max(app.Lap)];
            app.WearFRAxes.XLim = [0 max(app.Lap)];
            app.WearRLAxes.XLim = [0 max(app.Lap)];
            app.WearRRAxes.XLim = [0 max(app.Lap)];

            % Change Y-axis limit for visibility
            app.WearFLAxes.YLim = [0 100];
            app.WearFRAxes.YLim = [0 100];
            app.WearRLAxes.YLim = [0 100];
            app.WearRRAxes.YLim = [0 100];

            % Legend            
            legend(app.WearFRAxes, FRwear(1:length(app.Carstoplot)),'Location','northeast');            
            hold(app.WearFLAxes,'off'); hold(app.WearFRAxes,'off'); hold(app.WearRLAxes,'off'); hold(app.WearRRAxes,'off');            
        end

        function plotPitStops(app)
            % Clear and hold axis
            cla(app.PitStopsAxes);
            hold(app.PitStopsAxes,'on');

            % Plotting index            
            position = app.UITableDrivers.Data.P(app.UITableDrivers.Data.S == 1);

            for a = 1:length(position)
                idx = app.Pitattribute & ismember(app.FinishPos,position(a)) & app.EstimatedStopLapTime > 0 & app.EstimatedStopSectorTime > 0;

                % Set axes
                XData = app.Lap(idx);
                YData = mean([app.EstimatedStopLapTime(idx) app.EstimatedStopSectorTime(idx)],2);
                str = append('P',string(position(a)));

                % Plot
                scatter(app.PitStopsAxes, XData, YData, 36, 'filled');
                text(app.PitStopsAxes, XData, YData, str);
            end

            % Class average
            carClass = unique(app.UITableDrivers.Data.Class(app.UITableDrivers.Data.S == 1));

            for a = 1:length(carClass)
                idx = app.Pitattribute & ismember(app.CarClass,carClass(a)) & app.EstimatedStopLapTime > 0 & app.EstimatedStopSectorTime > 0;

                YData = median([app.EstimatedStopLapTime(idx) app.EstimatedStopSectorTime(idx)],'all');

                yline(app.PitStopsAxes,YData,"LineWidth",3,"Label",carClass(a),'LabelOrientation','horizontal');
            end

            % Hold off
            hold(app.PitStopsAxes,'off');
            
            % Change X-axis limit for visibility
            app.PitStopsAxes.XLim = [0 max(app.Lap)];
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default appearance for axes
            
            % set header table parameters
            app.UITableHeaders.ColumnName = {'Slt','Time','Session','Setting','Mod','Track','Km','Event','Vehicles','Race laps','Race time','Drivers','Swaps','Obs','Setup','xFuel','xWear','F/W log'};
            app.UITableHeaders.ColumnEditable = [true false false false false false false false false false false false false false false false false false];
            app.UITableHeaders.ColumnWidth = {40,'auto',80,80,80,80,80,80,80,85,85,80,80,80,80,80,80,80};
            app.UITableHeaders.ColumnSortable = true;

            % check if databases are linked
            try
                temp = load('headers.mat');             
                if ~isempty(fieldnames(temp))
                    app.UITableHeaders.Data = temp.headers;
                end
            catch
                app.UITableHeaders.Data = {};
            end
        end

        % Menu selected function: LoadXMLlogMenu
        function LoadXMLlogMenuSelected(app, event)
            [file,path] = uigetfile('*.xml','MultiSelect','on');
            numfiles = size(string(file),2);

            if ~isa(file,'double') % user specified path to the file ("file" is not 0)                

                % Define error counter
                errorCount = 0;

                % Clear all axes and legends
                arrayfun(@cla,findall(0,'type','axes'));
                if ~isempty(app.UITableDrivers.Data)
                    app.UITableDrivers.Data.S(:) = 0;
                end

                % Start working with files
                for a = 1:numfiles
                    if numfiles == 1
                        fileToLoad = file; % "file" is one char array
                    else
                        fileToLoad = file{a}; % "file" is cell array
                    end

                    % Create loading figure
                    str = sprintf('Reading XML log #%d out of %d',a,numfiles);
                    uiprogressdlg(app.SEAT,'Title',str,'Indeterminate','on');

                    % Try to load file
                    try
                        app.Log = readstruct(append(path,fileToLoad));
                    catch
                        errorCount = errorCount + 1;
                        continue
                    end

                    % Determine type of session and call respective function to unpack data
                    if isfield(app.Log,'RaceResults')

                        % Update loading figure
                        uiprogressdlg(app.SEAT,'Title','Preparing data','Indeterminate','on');

                        % Start loading data
                        if isfield(app.Log.RaceResults,'TestDay')
                            try
                                app.SessionResults = app.Log.RaceResults.TestDay.Driver;
                                app.Session = 'Test day';
                                unpackLog(app);
                            catch
                                errorCount = errorCount + 1;
                                continue
                            end

                        elseif isfield(app.Log.RaceResults,'Practice1')
                            try
                                app.SessionResults = app.Log.RaceResults.Practice1.Driver;
                                app.Session = 'Practice';
                                unpackLog(app);
                            catch
                                errorCount = errorCount + 1;
                                continue
                            end

                        elseif isfield(app.Log.RaceResults,'Qualify')
                            try
                                app.SessionResults = app.Log.RaceResults.Qualify.Driver;
                                app.Session = 'Qualifying';
                                unpackLog(app);
                            catch
                                errorCount = errorCount + 1;
                                continue
                            end

                        elseif isfield(app.Log.RaceResults,'Warmup')
                            try
                                app.SessionResults = app.Log.RaceResults.Warmup.Driver;
                                app.Session = 'Warmup';
                                unpackLog(app);
                            catch
                                errorCount = errorCount + 1;
                                continue
                            end

                        elseif isfield(app.Log.RaceResults,'Race')
                            try
                                app.SessionResults = app.Log.RaceResults.Race.Driver;
                                app.Session = 'Race';
                                if isfield(app.SessionResults(1),'Swap')
                                    app.Swap = true;
                                else
                                    app.Swap = false;
                                end
                                unpackLog(app);
                            catch
                                errorCount = errorCount + 1;
                                continue
                            end

                        else
                            errorCount = errorCount + 1;
                            continue
                        end

                        % Check if fuel and tyre wear is logged
                        if all(isnan(app.Fuelperc)) && all(isnan(app.Twa))
                            app.FWLogged = "No";
                        else
                            app.FWLogged = "Yes";
                        end

                        % Update databases
                        uiprogressdlg(app.SEAT,'Title','Updating databases','Indeterminate','on');
                        updateDatabase(app);
                        generateSessionSummary(app);
                    else
                        errorCount = errorCount + 1;
                        continue
                    end
                end

                % Set default settings
                uiprogressdlg(app.SEAT,'Title','Restoring default settings','Indeterminate','on');
                setDefaults(app);

                % Load event info in GUI
                uiprogressdlg(app.SEAT,'Title','Loading data','Indeterminate','on');
                loadSessionDataFromXML(app);
                loadEventinfo(app);

                % Load data in the reports
                uiprogressdlg(app.SEAT,'Title','Preparing session reports','Indeterminate','on');                
                loadSessionSummary(app);
                driverPerformance(app);
                stintPerformance(app);
                timecards(app);
                pitStops(app);
                updateFields(app);

                app.SessionSummary = []; % clear type of session flag

                if errorCount > 0
                    str = sprintf('%d files loaded, %d errors',numfiles,errorCount);
                    uialert(app.SEAT,str,'Result','Icon','info');
                end
            end
        end

        % Close request function: SEAT
        function SEATCloseRequest(app, event)
            delete(app.AboutApp);
            delete(app);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app.AboutApp);
            delete(app);
        end

        % Value changed function: SelectallCheckBox
        function SelectallCheckBoxValueChanged(app, event)
            if app.SelectallCheckBox.Value == 1
                app.UITableDrivers.Data.S = true(length(app.UITableDrivers.Data.S),1);
            end

            if app.SelectallCheckBox.Value == 0
                app.UITableDrivers.Data.S = false(length(app.UITableDrivers.Data.S),1);
            end

            UITableDriversCellEdit(app);
        end

        % Cell edit callback: UITableDrivers
        function UITableDriversCellEdit(app, event)
            % Set cars to plot for all graphing functions
            if app.Swap
                % make unique Ids for teams
                pos = string(app.UITableDrivers.Data.P(app.UITableDrivers.Data.S == 1));
                teamname = app.UITableDrivers.Data.Team(app.UITableDrivers.Data.S == 1);
                app.Carstoplot = unique(append("#",pos," ",teamname));
            else
                % drivers
                app.Carstoplot = app.UITableDrivers.Data.Name(app.UITableDrivers.Data.S == 1);
            end

            % Update all graphs if at least 1 driver is selected
            if ~isempty(app.Carstoplot)
                updateFields(app);

                if app.TabGroupGraphs.SelectedTab == app.GapsTab
                    plotGaps(app);
                elseif app.TabGroupGraphs.SelectedTab == app.LapsTab
                    plotLaps(app);
                elseif app.TabGroupGraphs.SelectedTab == app.SectorsTab
                    plotSectors(app);
                elseif app.TabGroupGraphs.SelectedTab == app.PositionsTab
                    plotPosition(app);
                elseif app.TabGroupGraphs.SelectedTab == app.PaceTab
                    plotPace(app)
                elseif app.TabGroupGraphs.SelectedTab == app.FuelTab
                    plotFuel(app);
                elseif app.TabGroupGraphs.SelectedTab == app.WearTab
                    plotTyreWear(app);
                elseif app.TabGroupGraphs.SelectedTab == app.PitsTab
                    plotPitStops(app);
                end
            end
        end

        % Value changed function: caplitersEditField
        function caplitersEditFieldValueChanged(app, event)
            if ~isempty(app.UITableDrivers.Data)
                updateFields(app);
                UITableDriversCellEdit(app);
            end
        end

        % Menu selected function: SessionMenu
        function SessionMenuSelected(app, event)
            [file,path] = uiputfile('*.csv');

            if ~isa(file,'double') && ~isa(path,'double') % user specified path to the file
                filename = fullfile(path,file);
                t = app.UITableSession.Data;
                writetable(t,filename);
            end
        end

        % Menu selected function: DriversPerformanceMenu
        function DriversPerformanceMenuSelected(app, event)
            [file,path] = uiputfile('*.csv');

            if ~isa(file,'double') && ~isa(path,'double')
                filename = fullfile(path,file);
                t = app.UITableDriversPerformance.Data;
                writetable(t,filename);
            end
        end

        % Menu selected function: TimecardsMenu
        function TimecardsMenuSelected(app, event)
            [file,path] = uiputfile('*.csv');

            if ~isa(file,'double') && ~isa(path,'double')
                filename = fullfile(path,file);
                t = app.Timecards;
                writetable(t,filename);
            end
        end

        % Menu selected function: PitstopsMenu
        function PitstopsMenuSelected(app, event)
            [file,path] = uiputfile('*.csv');

            if ~isa(file,'double') && ~isa(path,'double')
                filename = fullfile(path,file);
                t = app.UITablePitstops.Data;
                writetable(t,filename);
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            % Disable About menu while dialog is open
            app.AboutMenu.Enable = 'off';

            % Open the options dialog and pass inputs
            app.AboutApp = About(app);
        end

        % Selection change function: TabGroupGraphs
        function TabGroupGraphsSelectionChanged(app, event)
            UITableDriversCellEdit(app);
        end

        % Menu selected function: DeleteselectedsessionsMenu
        function DeleteselectedsessionsMenuSelected(app, event)
            if sum(app.UITableHeaders.Data.select) ~= 0 % at least one session is selected
                % find session names
                time = app.UITableHeaders.Data.time(app.UITableHeaders.Data.select);
                sessionReference = app.UITableHeaders.Data.session(app.UITableHeaders.Data.select);

                % delete all selected sessions
                for a = 1:length(time)
                    name = sprintf('%s_%s.mat',time(a),sessionReference(a));
                    name = strrep(name,'/',''); name = strrep(name,':',''); name = strrep(name,' ','_');
                    nameSummary = sprintf('%s_%s_Summary.mat',time(a),sessionReference(a));
                    nameSummary = strrep(nameSummary,'/',''); nameSummary = strrep(nameSummary,':',''); nameSummary = strrep(nameSummary,' ','_');

                    if ~isdeployed
                        delete(name);
                        delete(nameSummary);
                    else
                        delete(fullfile(ctfroot,'SEAT',name));
                        delete(fullfile(ctfroot,'SEAT',nameSummary));
                    end                    
                end

                % update databases tab
                app.UITableHeaders.Data = app.UITableHeaders.Data(~app.UITableHeaders.Data.select,:);
                
                % update headers file
                headers = app.UITableHeaders.Data;                
                if ~isdeployed
                    save('headers.mat','headers');
                else
                    save(fullfile(ctfroot, 'SEAT', 'headers.mat'), 'headers');
                end
            end
        end

        % Menu selected function: LoadonselectionMenu
        function LoadonselectionMenuSelected(app, event)
            if app.LoadonselectionMenu.Checked
                app.LoadonselectionMenu.Checked = 0;
            else
                app.LoadonselectionMenu.Checked = 1;
            end
        end

        % Cell edit callback: UITableHeaders
        function UITableHeadersCellEdit(app, event)
            if app.LoadonselectionMenu.Checked
                selectedRow = event.Indices(1); % find what row was selected
                if sum(app.UITableHeaders.Data.select) == 0 % user unticked selected row - recover it
                    app.UITableHeaders.Data.select(selectedRow) = true;
                else % something was ticked in the DB before
                    % remove previously ticked value and update DB
                    if sum(app.UITableHeaders.Data.select) > 1
                        app.UITableHeaders.Data.select(:) = false;
                        app.UITableHeaders.Data.select(selectedRow) = true;
                    end

                    % Clear all axes and legends
                    arrayfun(@cla,findall(0,'type','axes'));
                    if ~isempty(app.UITableDrivers.Data)
                        app.UITableDrivers.Data.S(:) = 0;
                    end

                    % load database
                    try
                        loadDatabase(app);
                    catch ME
                        errorfigure = app.SEAT;
                        uialert(errorfigure,ME.message,'Error');
                        return
                    end

                    % Save session data
                    app.Session = app.UITableHeaders.Data.session(selectedRow);

                    % run other functions
                    % Set default settings
                    loadfigure = app.SEAT;
                    d = uiprogressdlg(loadfigure,'Title','Restoring default settings','Indeterminate','on');
                    setDefaults(app);

                    % Load event info in GUI
                    uiprogressdlg(loadfigure,'Title','Loading data','Indeterminate','on');
                    loadSessionDataFromMat(app);
                    loadEventinfo(app);

                    % Load data in the reports
                    uiprogressdlg(loadfigure,'Title','Preparing session reports','Indeterminate','on');
                    loadSessionSummary(app);
                    driverPerformance(app);
                    stintPerformance(app);
                    timecards(app);
                    pitStops(app);
                    updateFields(app);

                    close(d);
                end
            end
        end

        % Menu selected function: StintsMenu
        function StintsMenuSelected(app, event)
            [file,path] = uiputfile('*.csv');

            if ~isa(file,'double') && ~isa(path,'double')
                filename = fullfile(path,file);
                t = app.UITableStints.Data;
                writetable(t,filename);
            end
        end

        % Value changed function: TrafficCheckBox
        function TrafficCheckBoxValueChanged(app, event)
            if ~isempty(app.UITableDrivers.Data)
                UITableDriversCellEdit(app);
            end
        end

        % Value changed function: CompoundsCheckBox
        function CompoundsCheckBoxValueChanged(app, event)
            if ~isempty(app.UITableDrivers.Data)
                UITableDriversCellEdit(app);
            end
        end

        % Value changed function: FuelRemainingCheckBox
        function FuelRemainingCheckBoxValueChanged(app, event)
            if ~isempty(app.UITableDrivers.Data)
                UITableDriversCellEdit(app);
            end
        end

        % Value changed function: FuelAddedCheckBox
        function FuelAddedCheckBoxValueChanged(app, event)
            if ~isempty(app.UITableDrivers.Data)
                UITableDriversCellEdit(app);
            end
        end

        % Value changed function: OvertakesCheckBox
        function OvertakesCheckBoxValueChanged(app, event)
            if ~isempty(app.UITableDrivers.Data)
                UITableDriversCellEdit(app);
            end
        end

        % Value changed function: DriversCheckBox
        function DriversCheckBoxValueChanged(app, event)
            if ~isempty(app.UITableDrivers.Data)
                UITableDriversCellEdit(app);
            end
        end

        % Value changed function: ReferenceCarDropDown
        function ReferenceCarDropDownValueChanged(app, event)
            if ~isempty(app.UITableDrivers.Data)
                UITableDriversCellEdit(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SEAT = uifigure('Visible', 'off');
            colormap(app.SEAT, 'summer');
            app.SEAT.Name = 'SEAT for rFactor 2';
            app.SEAT.Icon = fullfile(pathToMLAPP, 'SEAT_logo_2.png');
            app.SEAT.CloseRequestFcn = createCallbackFcn(app, @SEATCloseRequest, true);
            app.FileMenu = uimenu(app.SEAT);
            app.FileMenu.Text = 'File';
            app.LoadXMLlogMenu = uimenu(app.FileMenu);
            app.LoadXMLlogMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadXMLlogMenuSelected, true);
            app.LoadXMLlogMenu.Accelerator = 'O';
            app.LoadXMLlogMenu.Text = 'Load XML log';
            app.AboutMenu = uimenu(app.FileMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Accelerator = 'Q';
            app.QuitMenu.Text = 'Quit';
            app.DatabaseMenu = uimenu(app.SEAT);
            app.DatabaseMenu.Text = 'Database';
            app.DeleteselectedsessionsMenu = uimenu(app.DatabaseMenu);
            app.DeleteselectedsessionsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteselectedsessionsMenuSelected, true);
            app.DeleteselectedsessionsMenu.Text = 'Delete selected sessions';
            app.LoadonselectionMenu = uimenu(app.DatabaseMenu);
            app.LoadonselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadonselectionMenuSelected, true);
            app.LoadonselectionMenu.Checked = 'on';
            app.LoadonselectionMenu.Text = 'Load on selection';
            app.ReportsMenu = uimenu(app.SEAT);
            app.ReportsMenu.Text = 'Reports';
            app.SessionMenu = uimenu(app.ReportsMenu);
            app.SessionMenu.MenuSelectedFcn = createCallbackFcn(app, @SessionMenuSelected, true);
            app.SessionMenu.Enable = 'off';
            app.SessionMenu.Text = 'Session';
            app.DriversPerformanceMenu = uimenu(app.ReportsMenu);
            app.DriversPerformanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DriversPerformanceMenuSelected, true);
            app.DriversPerformanceMenu.Enable = 'off';
            app.DriversPerformanceMenu.Text = 'Drivers';
            app.StintsMenu = uimenu(app.ReportsMenu);
            app.StintsMenu.MenuSelectedFcn = createCallbackFcn(app, @StintsMenuSelected, true);
            app.StintsMenu.Enable = 'off';
            app.StintsMenu.Text = 'Stints';
            app.TimecardsMenu = uimenu(app.ReportsMenu);
            app.TimecardsMenu.MenuSelectedFcn = createCallbackFcn(app, @TimecardsMenuSelected, true);
            app.TimecardsMenu.Enable = 'off';
            app.TimecardsMenu.Text = 'Timecards';
            app.PitstopsMenu = uimenu(app.ReportsMenu);
            app.PitstopsMenu.MenuSelectedFcn = createCallbackFcn(app, @PitstopsMenuSelected, true);
            app.PitstopsMenu.Enable = 'off';
            app.PitstopsMenu.Text = 'Pit stops';
            app.GridLayout = uigridlayout(app.SEAT);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DatabaseTab = uitab(app.TabGroup);
            app.DatabaseTab.Title = 'Database';
            app.GridLayout6 = uigridlayout(app.DatabaseTab);
            app.UITableHeaders = uitable(app.GridLayout6);
            app.UITableHeaders.ColumnName = '';
            app.UITableHeaders.RowName = {};
            app.UITableHeaders.CellEditCallback = createCallbackFcn(app, @UITableHeadersCellEdit, true);
            app.ReportsTab = uitab(app.TabGroup);
            app.ReportsTab.Title = 'Reports';
            app.GridLayout7 = uigridlayout(app.ReportsTab);
            app.TabGroupReports = uitabgroup(app.GridLayout7);
            app.SessionTab = uitab(app.TabGroupReports);
            app.SessionTab.Title = 'Session';
            app.GridLayout10 = uigridlayout(app.SessionTab);
            app.UITableSession = uitable(app.GridLayout10);
            app.UITableSession.ColumnName = '';
            app.UITableSession.RowName = {};
            app.UITableSession.ColumnSortable = true;
            app.DriversTab = uitab(app.TabGroupReports);
            app.DriversTab.Title = 'Drivers';
            app.GridLayout11 = uigridlayout(app.DriversTab);
            app.UITableDriversPerformance = uitable(app.GridLayout11);
            app.UITableDriversPerformance.ColumnName = '';
            app.UITableDriversPerformance.RowName = {};
            app.UITableDriversPerformance.ColumnSortable = true;
            app.StintsTab = uitab(app.TabGroupReports);
            app.StintsTab.Title = 'Stints';
            app.GridLayout26 = uigridlayout(app.StintsTab);
            app.UITableStints = uitable(app.GridLayout26);
            app.UITableStints.ColumnName = '';
            app.UITableStints.RowName = {};
            app.UITableStints.ColumnSortable = true;
            app.PitstopsTab = uitab(app.TabGroupReports);
            app.PitstopsTab.Title = 'Pit stops';
            app.GridLayout14 = uigridlayout(app.PitstopsTab);
            app.UITablePitstops = uitable(app.GridLayout14);
            app.UITablePitstops.ColumnName = '';
            app.UITablePitstops.RowName = {};
            app.UITablePitstops.ColumnSortable = true;
            app.SessionPanel = uipanel(app.GridLayout7);
            app.SessionPanel.Title = 'Session';
            app.GridLayout8 = uigridlayout(app.SessionPanel);
            app.TypeEditFieldLabel = uilabel(app.GridLayout8);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.GridLayout8, 'text');
            app.TypeEditField.Editable = 'off';
            app.SettingEditFieldLabel = uilabel(app.GridLayout8);
            app.SettingEditFieldLabel.Text = 'Setting';
            app.SettingEditField = uieditfield(app.GridLayout8, 'text');
            app.SettingEditField.Editable = 'off';
            app.ModEditFieldLabel = uilabel(app.GridLayout8);
            app.ModEditFieldLabel.Text = 'Mod';
            app.ModEditField = uieditfield(app.GridLayout8, 'text');
            app.ModEditField.Editable = 'off';
            app.EventEditFieldLabel = uilabel(app.GridLayout8);
            app.EventEditFieldLabel.Text = 'Event';
            app.EventEditField = uieditfield(app.GridLayout8, 'text');
            app.EventEditField.Editable = 'off';
            app.DateEditFieldLabel = uilabel(app.GridLayout8);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.GridLayout8, 'text');
            app.DateEditField.Editable = 'off';
            app.TimeEditFieldLabel = uilabel(app.GridLayout8);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.GridLayout8, 'text');
            app.TimeEditField.Editable = 'off';
            app.TrackEditFieldLabel = uilabel(app.GridLayout8);
            app.TrackEditFieldLabel.Text = 'Track';
            app.TrackEditField = uieditfield(app.GridLayout8, 'text');
            app.TrackEditField.Editable = 'off';
            app.LengthkmLabel = uilabel(app.GridLayout8);
            app.LengthkmLabel.Text = 'Length, km';
            app.LengthEditField_km = uieditfield(app.GridLayout8, 'numeric');
            app.LengthEditField_km.ValueDisplayFormat = '%0.3f';
            app.LengthEditField_km.Editable = 'off';
            app.LapsEditFieldLabel = uilabel(app.GridLayout8);
            app.LapsEditFieldLabel.Text = 'Laps';
            app.LapsEditField = uieditfield(app.GridLayout8, 'numeric');
            app.LapsEditField.ValueDisplayFormat = '%0.0f';
            app.LapsEditField.Editable = 'off';
            app.TimeminEditFieldLabel = uilabel(app.GridLayout8);
            app.TimeminEditFieldLabel.Text = 'Time, min';
            app.TimeminEditField = uieditfield(app.GridLayout8, 'numeric');
            app.TimeminEditField.Editable = 'off';
            app.SummaryPanel = uipanel(app.GridLayout7);
            app.SummaryPanel.Title = 'Summary';
            app.GridLayout_4 = uigridlayout(app.SummaryPanel);
            app.OvertakesEditFieldLabel = uilabel(app.GridLayout_4);
            app.OvertakesEditFieldLabel.Text = 'Overtakes';
            app.OvertakesEditField = uieditfield(app.GridLayout_4, 'numeric');
            app.OvertakesEditField.Limits = [0 Inf];
            app.OvertakesEditField.ValueDisplayFormat = '%.0f';
            app.OvertakesEditField.Editable = 'off';
            app.intrafficLabel = uilabel(app.GridLayout_4);
            app.intrafficLabel.Text = '% of laps in traffic';
            app.percintrafficEditField = uieditfield(app.GridLayout_4, 'numeric');
            app.percintrafficEditField.Limits = [0 100];
            app.percintrafficEditField.ValueDisplayFormat = '%.0f';
            app.percintrafficEditField.Editable = 'off';
            app.percintrafficEditField.Value = 0.264172;
            app.TheoreticalbestEditFieldLabel = uilabel(app.GridLayout_4);
            app.TheoreticalbestEditFieldLabel.Text = 'Theoretical best';
            app.TBEditField = uieditfield(app.GridLayout_4, 'text');
            app.TBEditField.Editable = 'off';
            app.FastestlapEditFieldLabel = uilabel(app.GridLayout_4);
            app.FastestlapEditFieldLabel.Text = 'Fastest lap';
            app.FLEditField = uieditfield(app.GridLayout_4, 'text');
            app.FLEditField.Editable = 'off';
            app.Sector1EditFieldLabel = uilabel(app.GridLayout_4);
            app.Sector1EditFieldLabel.Text = 'Sector 1';
            app.S1EditField = uieditfield(app.GridLayout_4, 'text');
            app.S1EditField.Editable = 'off';
            app.Sector2EditFieldLabel = uilabel(app.GridLayout_4);
            app.Sector2EditFieldLabel.Text = 'Sector 2';
            app.S2EditField = uieditfield(app.GridLayout_4, 'text');
            app.S2EditField.Editable = 'off';
            app.Sector3EditFieldLabel = uilabel(app.GridLayout_4);
            app.Sector3EditFieldLabel.Text = 'Sector 3';
            app.S3EditField = uieditfield(app.GridLayout_4, 'text');
            app.S3EditField.Editable = 'off';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.GridLayout15 = uigridlayout(app.GraphsTab);
            app.FuelPanel = uipanel(app.GridLayout15);
            app.FuelPanel.Title = 'Fuel';
            app.GridLayout_3 = uigridlayout(app.FuelPanel);
            app.caplitersEditFieldLabel = uilabel(app.GridLayout_3);
            app.caplitersEditFieldLabel.Text = 'cap., liters';
            app.caplitersEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.caplitersEditField.Limits = [1 200];
            app.caplitersEditField.ValueDisplayFormat = '%.1f';
            app.caplitersEditField.ValueChangedFcn = createCallbackFcn(app, @caplitersEditFieldValueChanged, true);
            app.caplitersEditField.Editable = 'off';
            app.caplitersEditField.Value = 1;
            app.literslapEditFieldLabel = uilabel(app.GridLayout_3);
            app.literslapEditFieldLabel.Text = 'liters/lap';
            app.literslapEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.literslapEditField.ValueDisplayFormat = '%.2f';
            app.literslapEditField.Editable = 'off';
            app.literskmEditFieldLabel = uilabel(app.GridLayout_3);
            app.literskmEditFieldLabel.Text = 'liters/km';
            app.literskmEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.literskmEditField.ValueDisplayFormat = '%.2f';
            app.literskmEditField.Editable = 'off';
            app.lapsEditFieldLabel = uilabel(app.GridLayout_3);
            app.lapsEditFieldLabel.Text = 'laps';
            app.lapsEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.lapsEditField.ValueDisplayFormat = '%.1f';
            app.lapsEditField.Editable = 'off';
            app.minEditFieldLabel = uilabel(app.GridLayout_3);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.minEditField.ValueDisplayFormat = '%.1f';
            app.minEditField.Editable = 'off';
            app.SelectallCheckBox = uicheckbox(app.GridLayout15);
            app.SelectallCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectallCheckBoxValueChanged, true);
            app.SelectallCheckBox.Enable = 'off';
            app.SelectallCheckBox.Text = 'Select all';
            app.UITableDrivers = uitable(app.GridLayout15);
            app.UITableDrivers.ColumnName = '';
            app.UITableDrivers.RowName = {};
            app.UITableDrivers.ColumnSortable = true;
            app.UITableDrivers.CellEditCallback = createCallbackFcn(app, @UITableDriversCellEdit, true);
            app.TabGroupGraphs = uitabgroup(app.GridLayout15);
            app.TabGroupGraphs.SelectionChangedFcn = createCallbackFcn(app, @TabGroupGraphsSelectionChanged, true);
            app.GapsTab = uitab(app.TabGroupGraphs);
            app.GapsTab.Title = 'Gaps';
            app.GridLayout25 = uigridlayout(app.GapsTab);
            app.GapsAxes = uiaxes(app.GridLayout25);
            xlabel(app.GapsAxes, 'Lap')
            ylabel(app.GapsAxes, 'Delta to reference car, seconds')
            zlabel(app.GapsAxes, 'Z')
            colormap(app.GapsAxes, 'summer')
            app.LapsTab = uitab(app.TabGroupGraphs);
            app.LapsTab.Title = 'Laps';
            app.GridLayout17 = uigridlayout(app.LapsTab);
            app.LapsAxes = uiaxes(app.GridLayout17);
            xlabel(app.LapsAxes, 'Lap')
            ylabel(app.LapsAxes, 'Lap time, seconds')
            zlabel(app.LapsAxes, 'Z')
            colormap(app.LapsAxes, 'summer')
            app.SectorsTab = uitab(app.TabGroupGraphs);
            app.SectorsTab.Title = 'Sectors';
            app.GridLayout18 = uigridlayout(app.SectorsTab);
            app.S1Axes = uiaxes(app.GridLayout18);
            title(app.S1Axes, 'Sector 1')
            xlabel(app.S1Axes, 'Lap')
            ylabel(app.S1Axes, 'Sector 1, seconds')
            zlabel(app.S1Axes, 'Z')
            colormap(app.S1Axes, 'summer')
            app.S2Axes = uiaxes(app.GridLayout18);
            title(app.S2Axes, 'Sector 2')
            xlabel(app.S2Axes, 'Lap')
            ylabel(app.S2Axes, 'Sector 2, seconds')
            zlabel(app.S2Axes, 'Z')
            colormap(app.S2Axes, 'summer')
            app.S3Axes = uiaxes(app.GridLayout18);
            title(app.S3Axes, 'Sector 3')
            xlabel(app.S3Axes, 'Lap')
            ylabel(app.S3Axes, 'Sector 3, seconds')
            zlabel(app.S3Axes, 'Z')
            colormap(app.S3Axes, 'summer')
            app.PositionsTab = uitab(app.TabGroupGraphs);
            app.PositionsTab.Title = 'Positions';
            app.GridLayout21 = uigridlayout(app.PositionsTab);
            app.PositionAxes = uiaxes(app.GridLayout21);
            xlabel(app.PositionAxes, 'Lap')
            ylabel(app.PositionAxes, 'Position in class')
            zlabel(app.PositionAxes, 'Z')
            colormap(app.PositionAxes, 'summer')
            app.PaceTab = uitab(app.TabGroupGraphs);
            app.PaceTab.Title = 'Pace';
            app.GridLayout28 = uigridlayout(app.PaceTab);
            app.PaceAxes = uiaxes(app.GridLayout28);
            xlabel(app.PaceAxes, 'Driver')
            ylabel(app.PaceAxes, 'Lap time, seconds')
            app.SortedTimeAxes = uiaxes(app.GridLayout28);
            xlabel(app.SortedTimeAxes, 'Sorted lap')
            ylabel(app.SortedTimeAxes, 'Lap time, seconds')
            zlabel(app.SortedTimeAxes, 'Z')
            app.FuelTab = uitab(app.TabGroupGraphs);
            app.FuelTab.Title = 'Fuel';
            app.GridLayout22 = uigridlayout(app.FuelTab);
            app.FuelAxes = uiaxes(app.GridLayout22);
            xlabel(app.FuelAxes, 'Lap')
            ylabel(app.FuelAxes, 'Remaining fuel, liters')
            zlabel(app.FuelAxes, 'Z')
            colormap(app.FuelAxes, 'summer')
            app.WearTab = uitab(app.TabGroupGraphs);
            app.WearTab.Title = 'Wear';
            app.GridLayout23 = uigridlayout(app.WearTab);
            app.WearFLAxes = uiaxes(app.GridLayout23);
            title(app.WearFLAxes, 'Front left')
            xlabel(app.WearFLAxes, 'Lap')
            ylabel(app.WearFLAxes, 'Remaining FL tyre, %')
            zlabel(app.WearFLAxes, 'Z')
            colormap(app.WearFLAxes, 'summer')
            app.WearFRAxes = uiaxes(app.GridLayout23);
            title(app.WearFRAxes, 'Front right')
            xlabel(app.WearFRAxes, 'Lap')
            ylabel(app.WearFRAxes, 'Remaining FR tyre, %')
            zlabel(app.WearFRAxes, 'Z')
            colormap(app.WearFRAxes, 'summer')
            app.WearRLAxes = uiaxes(app.GridLayout23);
            title(app.WearRLAxes, 'Rear left')
            xlabel(app.WearRLAxes, 'Lap')
            ylabel(app.WearRLAxes, 'Remaining RL tyre, %')
            zlabel(app.WearRLAxes, 'Z')
            colormap(app.WearRLAxes, 'summer')
            app.WearRRAxes = uiaxes(app.GridLayout23);
            title(app.WearRRAxes, 'Rear right')
            xlabel(app.WearRRAxes, 'Lap')
            ylabel(app.WearRRAxes, 'Remaining RR tyre, %')
            zlabel(app.WearRRAxes, 'Z')
            colormap(app.WearRRAxes, 'summer')
            app.PitsTab = uitab(app.TabGroupGraphs);
            app.PitsTab.Title = 'Pits';
            app.GridLayout27 = uigridlayout(app.PitsTab);
            app.PitStopsAxes = uiaxes(app.GridLayout27);
            xlabel(app.PitStopsAxes, 'Lap')
            ylabel(app.PitStopsAxes, 'Seconds')
            zlabel(app.PitStopsAxes, 'Z')
            app.OptionsPanel = uipanel(app.GridLayout15);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout29 = uigridlayout(app.OptionsPanel);
            app.TrafficCheckBox = uicheckbox(app.GridLayout29);
            app.TrafficCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrafficCheckBoxValueChanged, true);
            app.TrafficCheckBox.Text = 'Traffic';
            app.CompoundsCheckBox = uicheckbox(app.GridLayout29);
            app.CompoundsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompoundsCheckBoxValueChanged, true);
            app.CompoundsCheckBox.Text = 'Compounds';
            app.FuelRemainingCheckBox = uicheckbox(app.GridLayout29);
            app.FuelRemainingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FuelRemainingCheckBoxValueChanged, true);
            app.FuelRemainingCheckBox.Text = 'Fuel remaining';
            app.FuelAddedCheckBox = uicheckbox(app.GridLayout29);
            app.FuelAddedCheckBox.ValueChangedFcn = createCallbackFcn(app, @FuelAddedCheckBoxValueChanged, true);
            app.FuelAddedCheckBox.Text = 'Fuel added';
            app.OvertakesCheckBox = uicheckbox(app.GridLayout29);
            app.OvertakesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OvertakesCheckBoxValueChanged, true);
            app.OvertakesCheckBox.Text = 'Overtakes';
            app.DriversCheckBox = uicheckbox(app.GridLayout29);
            app.DriversCheckBox.ValueChangedFcn = createCallbackFcn(app, @DriversCheckBoxValueChanged, true);
            app.DriversCheckBox.Text = 'Drivers';
            app.ReferencecarDropDownLabel = uilabel(app.GridLayout29);
            app.ReferencecarDropDownLabel.Text = 'Reference car';
            app.ReferenceCarDropDown = uidropdown(app.GridLayout29);
            app.ReferenceCarDropDown.Items = {};
            app.ReferenceCarDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceCarDropDownValueChanged, true);
            app.ReferenceCarDropDown.Value = {};
            % Show the figure after all components are created
            app.SEAT.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Release_2024_05

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SEAT)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SEAT)
        end
    end
end